classdef plotOrbits_application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Toolbar                     matlab.ui.container.Toolbar
        PushTool                    matlab.ui.container.toolbar.PushTool
        change_plane                matlab.ui.container.Panel
        D_vEditField_8              matlab.ui.control.NumericEditField
        D_vEditField_8Label         matlab.ui.control.Label
        OutputPanel_4               matlab.ui.container.Panel
        e_fEditField_4              matlab.ui.control.NumericEditField
        e_fEditField_4Label         matlab.ui.control.Label
        a_fEditField_4              matlab.ui.control.NumericEditField
        a_fEditField_4Label         matlab.ui.control.Label
        om_fEditField_4             matlab.ui.control.NumericEditField
        om_fEditField_4Label        matlab.ui.control.Label
        InputPanel_6                matlab.ui.container.Panel
        i_fEditField                matlab.ui.control.NumericEditField
        i_fEditFieldLabel           matlab.ui.control.Label
        OM_fEditField               matlab.ui.control.NumericEditField
        OM_fEditFieldLabel          matlab.ui.control.Label
        th_fEditField_5             matlab.ui.control.NumericEditField
        th_fEditField_5Label        matlab.ui.control.Label
        FinalorbitPanel_4           matlab.ui.container.Panel
        InputPanel_5                matlab.ui.container.Panel
        OM_iEditField_2             matlab.ui.control.NumericEditField
        OM_iEditField_2Label        matlab.ui.control.Label
        i_iEditField                matlab.ui.control.NumericEditField
        i_iEditFieldLabel           matlab.ui.control.Label
        th_iEditField_4             matlab.ui.control.NumericEditField
        th_iEditField_4Label        matlab.ui.control.Label
        om_iEditField_4             matlab.ui.control.NumericEditField
        om_iEditField_4Label        matlab.ui.control.Label
        e_iEditField_4              matlab.ui.control.NumericEditField
        e_iEditField_4Label         matlab.ui.control.Label
        a_iEditField_4              matlab.ui.control.NumericEditField
        a_iEditField_4Label         matlab.ui.control.Label
        FirstorbitPanel_5           matlab.ui.container.Panel
        dontchangeth_fCheckBox      matlab.ui.control.CheckBox
        ButtonGroup_6               matlab.ui.container.ButtonGroup
        MostefficientButton         matlab.ui.control.RadioButton
        FastestButton               matlab.ui.control.RadioButton
        Addtransfer_change_planeCheckBox  matlab.ui.control.CheckBox
        th_mEditField               matlab.ui.control.NumericEditField
        th_mEditFieldLabel          matlab.ui.control.Label
        TimeEditField_4             matlab.ui.control.NumericEditField
        TimeEditField_4Label        matlab.ui.control.Label
        useconstantsCheckBox_4      matlab.ui.control.CheckBox
        Run_elliptical_2            matlab.ui.control.Button
        elliptical_bi_panel         matlab.ui.container.Panel
        OutputPanel_3               matlab.ui.container.Panel
        om_fEditField               matlab.ui.control.NumericEditField
        om_fEditFieldLabel          matlab.ui.control.Label
        InputPanel_4                matlab.ui.container.Panel
        th_fEditField_2             matlab.ui.control.NumericEditField
        th_fEditField_2Label        matlab.ui.control.Label
        e_fEditField                matlab.ui.control.NumericEditField
        e_fEditFieldLabel           matlab.ui.control.Label
        a_fEditField                matlab.ui.control.NumericEditField
        a_fEditFieldLabel           matlab.ui.control.Label
        FinalorbitPanel_3           matlab.ui.container.Panel
        e_tEditField                matlab.ui.control.NumericEditField
        e_tEditFieldLabel           matlab.ui.control.Label
        a_tEditField                matlab.ui.control.NumericEditField
        a_tEditFieldLabel           matlab.ui.control.Label
        TransferorbitPanel          matlab.ui.container.Panel
        InputPanel_3                matlab.ui.container.Panel
        th_iEditField               matlab.ui.control.NumericEditField
        th_iEditFieldLabel          matlab.ui.control.Label
        om_iEditField               matlab.ui.control.NumericEditField
        om_iEditFieldLabel          matlab.ui.control.Label
        e_iEditField                matlab.ui.control.NumericEditField
        e_iEditFieldLabel           matlab.ui.control.Label
        a_iEditField                matlab.ui.control.NumericEditField
        a_iEditFieldLabel           matlab.ui.control.Label
        FirstorbitPanel_4           matlab.ui.container.Panel
        Addtransfer_ellipticalCheckBox_3  matlab.ui.control.CheckBox
        OMEditField_3               matlab.ui.control.NumericEditField
        OMEditField_3Label          matlab.ui.control.Label
        iEditField_3                matlab.ui.control.NumericEditField
        iEditField_3Label           matlab.ui.control.Label
        TimeEditField               matlab.ui.control.NumericEditField
        TimeEditFieldLabel          matlab.ui.control.Label
        D_v2EditField               matlab.ui.control.NumericEditField
        D_v2EditFieldLabel          matlab.ui.control.Label
        D_v1EditField               matlab.ui.control.NumericEditField
        D_v1EditFieldLabel          matlab.ui.control.Label
        useconstantsCheckBox        matlab.ui.control.CheckBox
        Run_elliptical              matlab.ui.control.Button
        ButtonGroup_2               matlab.ui.container.ButtonGroup
        perigeperigeButton          matlab.ui.control.RadioButton
        apogeapogeButton            matlab.ui.control.RadioButton
        apogeperigeButton           matlab.ui.control.RadioButton
        perigeapogeButton           matlab.ui.control.RadioButton
        FirstorbitPanel_3           matlab.ui.container.Panel
        TransferpanelPanel          matlab.ui.container.Panel
        StartButton                 matlab.ui.control.Button
        FinishButton                matlab.ui.control.Button
        TabGroup2                   matlab.ui.container.TabGroup
        ImpulseTab                  matlab.ui.container.Tab
        ChangeplaneButton           matlab.ui.control.StateButton
        PeriapsisangleButton        matlab.ui.control.StateButton
        ImpulsesTab_2               matlab.ui.container.Tab
        EllipticalbitangentButton   matlab.ui.control.StateButton
        ImpulsesTab_3               matlab.ui.container.Tab
        BiellipticButton            matlab.ui.control.StateButton
        SavedtransfersPanel         matlab.ui.container.Panel
        SixthtransferPanel          matlab.ui.container.Panel
        D_tEditField_6              matlab.ui.control.NumericEditField
        D_tEditField_6Label         matlab.ui.control.Label
        D_vEditField_7              matlab.ui.control.NumericEditField
        D_vEditField_7Label         matlab.ui.control.Label
        TextArea_6                  matlab.ui.control.TextArea
        FifthtransferPanel          matlab.ui.container.Panel
        D_tEditField_5              matlab.ui.control.NumericEditField
        D_tEditField_5Label         matlab.ui.control.Label
        D_vEditField_6              matlab.ui.control.NumericEditField
        D_vEditField_6Label         matlab.ui.control.Label
        TextArea_5                  matlab.ui.control.TextArea
        FourthtransferPanel         matlab.ui.container.Panel
        D_tEditField_4              matlab.ui.control.NumericEditField
        D_tEditField_4Label         matlab.ui.control.Label
        D_vEditField_5              matlab.ui.control.NumericEditField
        D_vEditField_5Label         matlab.ui.control.Label
        TextArea_4                  matlab.ui.control.TextArea
        ThirdtransferPanel          matlab.ui.container.Panel
        D_tEditField_3              matlab.ui.control.NumericEditField
        D_tEditField_3Label         matlab.ui.control.Label
        D_vEditField_4              matlab.ui.control.NumericEditField
        D_vEditField_4Label         matlab.ui.control.Label
        TextArea_3                  matlab.ui.control.TextArea
        SecondtransferPanel         matlab.ui.container.Panel
        D_tEditField_2              matlab.ui.control.NumericEditField
        D_tEditField_2Label         matlab.ui.control.Label
        D_vEditField_3              matlab.ui.control.NumericEditField
        D_vEditField_3Label         matlab.ui.control.Label
        TextArea_2                  matlab.ui.control.TextArea
        FirsttransferPanel          matlab.ui.container.Panel
        D_tEditField_1              matlab.ui.control.NumericEditField
        D_tEditFieldLabel           matlab.ui.control.Label
        D_vEditField_2              matlab.ui.control.NumericEditField
        D_vEditField_2Label         matlab.ui.control.Label
        TextArea_1                  matlab.ui.control.TextArea
        Panel2                      matlab.ui.container.Panel
        ButtonGroup_5               matlab.ui.container.ButtonGroup
        D_VButton                   matlab.ui.control.RadioButton
        D_tButton                   matlab.ui.control.RadioButton
        RunButton_5                 matlab.ui.control.StateButton
        ZBEditField                 matlab.ui.control.NumericEditField
        ZBEditFieldLabel            matlab.ui.control.Label
        YBEditField                 matlab.ui.control.NumericEditField
        YBEditFieldLabel            matlab.ui.control.Label
        XBEditField                 matlab.ui.control.NumericEditField
        XBEditFieldLabel            matlab.ui.control.Label
        VzBEditField                matlab.ui.control.NumericEditField
        VzBEditFieldLabel           matlab.ui.control.Label
        VyBEditField                matlab.ui.control.NumericEditField
        VyBEditFieldLabel           matlab.ui.control.Label
        VxBEditField                matlab.ui.control.NumericEditField
        VxBEditFieldLabel           matlab.ui.control.Label
        ZAEditField                 matlab.ui.control.NumericEditField
        ZAEditFieldLabel            matlab.ui.control.Label
        YAEditField                 matlab.ui.control.NumericEditField
        YAEditFieldLabel            matlab.ui.control.Label
        XAEditField                 matlab.ui.control.NumericEditField
        XAEditFieldLabel            matlab.ui.control.Label
        VzAEditField                matlab.ui.control.NumericEditField
        VzAEditFieldLabel           matlab.ui.control.Label
        VyAEditField                matlab.ui.control.NumericEditField
        VyAEditFieldLabel           matlab.ui.control.Label
        VxAEditField                matlab.ui.control.NumericEditField
        VxAEditFieldLabel           matlab.ui.control.Label
        bielliptic                  matlab.ui.container.Panel
        D_v3EditField               matlab.ui.control.NumericEditField
        D_v3EditFieldLabel          matlab.ui.control.Label
        D_v2EditField_2             matlab.ui.control.NumericEditField
        D_v2EditField_2Label        matlab.ui.control.Label
        OutputPanel_6               matlab.ui.container.Panel
        e_t2EditField               matlab.ui.control.NumericEditField
        e_t2EditFieldLabel          matlab.ui.control.Label
        a_t2EditField               matlab.ui.control.NumericEditField
        a_t2EditFieldLabel          matlab.ui.control.Label
        ndtransferorbitPanel        matlab.ui.container.Panel
        OutputPanel_5               matlab.ui.container.Panel
        e_t1EditField               matlab.ui.control.NumericEditField
        e_t1EditFieldLabel          matlab.ui.control.Label
        a_t1EditField               matlab.ui.control.NumericEditField
        a_t1EditFieldLabel          matlab.ui.control.Label
        sttransferorbitPanel        matlab.ui.container.Panel
        InputPanel_8                matlab.ui.container.Panel
        th_fEditField_3             matlab.ui.control.NumericEditField
        th_fEditField_3Label        matlab.ui.control.Label
        om_fEditField_2             matlab.ui.control.NumericEditField
        om_fEditField_2Label        matlab.ui.control.Label
        e_fEditField_2              matlab.ui.control.NumericEditField
        e_fEditField_2Label         matlab.ui.control.Label
        a_fEditField_2              matlab.ui.control.NumericEditField
        a_fEditField_2Label         matlab.ui.control.Label
        LastorbitPanel              matlab.ui.container.Panel
        InputPanel_7                matlab.ui.container.Panel
        th_iEditField_2             matlab.ui.control.NumericEditField
        th_iEditField_2Label        matlab.ui.control.Label
        om_iEditField_2             matlab.ui.control.NumericEditField
        om_iEditField_2Label        matlab.ui.control.Label
        e_iEditField_2              matlab.ui.control.NumericEditField
        e_iEditField_2Label         matlab.ui.control.Label
        a_iEditField_2              matlab.ui.control.NumericEditField
        a_iEditField_2Label         matlab.ui.control.Label
        FirstorbitPanel_6           matlab.ui.container.Panel
        Addtransfer_biellipticCheckBox_4  matlab.ui.control.CheckBox
        r_bEditField                matlab.ui.control.NumericEditField
        r_bEditFieldLabel           matlab.ui.control.Label
        OMEditField_4               matlab.ui.control.NumericEditField
        OMEditField_4Label          matlab.ui.control.Label
        iEditField_4                matlab.ui.control.NumericEditField
        iEditField_4Label           matlab.ui.control.Label
        TimeEditField_2             matlab.ui.control.NumericEditField
        TimeEditField_2Label        matlab.ui.control.Label
        D_v1EditField_2             matlab.ui.control.NumericEditField
        D_v1EditField_2Label        matlab.ui.control.Label
        ButtonGroup_3               matlab.ui.container.ButtonGroup
        apogeButton                 matlab.ui.control.RadioButton
        perigeButton                matlab.ui.control.RadioButton
        useconstantsCheckBox_2      matlab.ui.control.CheckBox
        Run_bielliptic              matlab.ui.control.Button
        periapsis                   matlab.ui.container.Panel
        OutputPanel_2               matlab.ui.container.Panel
        th_mEditField_3             matlab.ui.control.NumericEditField
        th_mEditField_3Label        matlab.ui.control.Label
        InputPanel_2                matlab.ui.container.Panel
        th_fEditField_4             matlab.ui.control.NumericEditField
        th_fEditField_4Label        matlab.ui.control.Label
        om_fEditField_3             matlab.ui.control.NumericEditField
        om_fEditField_3Label        matlab.ui.control.Label
        FinalorbitPanel_2           matlab.ui.container.Panel
        OutputPanel                 matlab.ui.container.Panel
        th_mEditField_2             matlab.ui.control.NumericEditField
        th_mEditField_2Label        matlab.ui.control.Label
        InputPanel                  matlab.ui.container.Panel
        th_iEditField_3             matlab.ui.control.NumericEditField
        th_iEditField_3Label        matlab.ui.control.Label
        om_iEditField_3             matlab.ui.control.NumericEditField
        om_iEditField_3Label        matlab.ui.control.Label
        e_iEditField_3              matlab.ui.control.NumericEditField
        e_iEditField_3Label         matlab.ui.control.Label
        a_iEditField_3              matlab.ui.control.NumericEditField
        a_iEditField_3Label         matlab.ui.control.Label
        FirstorbitPanel             matlab.ui.container.Panel
        Addtransfer_periapsisCheckBox_2  matlab.ui.control.CheckBox
        OMEditField_5               matlab.ui.control.NumericEditField
        OMEditField_5Label          matlab.ui.control.Label
        iEditField_5                matlab.ui.control.NumericEditField
        iEditField_5Label           matlab.ui.control.Label
        TimeEditField_3             matlab.ui.control.NumericEditField
        TimeEditField_3Label        matlab.ui.control.Label
        D_vEditField                matlab.ui.control.NumericEditField
        D_vEditFieldLabel           matlab.ui.control.Label
        e_fEditField_3              matlab.ui.control.NumericEditField
        e_fEditField_3Label         matlab.ui.control.Label
        a_fEditField_3              matlab.ui.control.NumericEditField
        a_fEditField_3Label         matlab.ui.control.Label
        ButtonGroup_4               matlab.ui.container.ButtonGroup
        pointbButton                matlab.ui.control.RadioButton
        pointaButton                matlab.ui.control.RadioButton
        useconstantsCheckBox_3      matlab.ui.control.CheckBox
        RunButton_4                 matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        InputTab                    matlab.ui.container.Tab
        omEditField_2               matlab.ui.control.NumericEditField
        omEditField_2Label          matlab.ui.control.Label
        Delta_thEditField           matlab.ui.control.NumericEditField
        Delta_thEditFieldLabel      matlab.ui.control.Label
        thEditField_2               matlab.ui.control.NumericEditField
        thEditField_2Label          matlab.ui.control.Label
        eEditField_2                matlab.ui.control.NumericEditField
        eEditField_2Label           matlab.ui.control.Label
        OMEditField_2               matlab.ui.control.NumericEditField
        OMEditField_2Label          matlab.ui.control.Label
        iEditField_2                matlab.ui.control.NumericEditField
        iEditField_2Label           matlab.ui.control.Label
        aKmEditField_2              matlab.ui.control.NumericEditField
        aKmEditField_2Label         matlab.ui.control.Label
        th_fEditField               matlab.ui.control.NumericEditField
        th_fEditFieldLabel          matlab.ui.control.Label
        th_0EditField               matlab.ui.control.NumericEditField
        th_0EditFieldLabel          matlab.ui.control.Label
        muEditField                 matlab.ui.control.NumericEditField
        muEditFieldLabel            matlab.ui.control.Label
        OtherconstantsTab           matlab.ui.container.Tab
        GridLayout                  matlab.ui.container.GridLayout
        raEditField                 matlab.ui.control.NumericEditField
        raEditFieldLabel            matlab.ui.control.Label
        rpEditField                 matlab.ui.control.NumericEditField
        rpEditFieldLabel            matlab.ui.control.Label
        bEditField                  matlab.ui.control.NumericEditField
        bEditFieldLabel             matlab.ui.control.Label
        hEditField_2                matlab.ui.control.NumericEditField
        hLabel                      matlab.ui.control.Label
        pEditField                  matlab.ui.control.NumericEditField
        pEditFieldLabel             matlab.ui.control.Label
        EEditField                  matlab.ui.control.NumericEditField
        EEditFieldLabel             matlab.ui.control.Label
        TransfersTab                matlab.ui.container.Tab
        D_vtotzEditField            matlab.ui.control.NumericEditField
        D_vtotzEditFieldLabel       matlab.ui.control.Label
        D_vtotyEditField            matlab.ui.control.NumericEditField
        D_vtotyEditFieldLabel       matlab.ui.control.Label
        D_vtotxEditField            matlab.ui.control.NumericEditField
        D_vtotxEditFieldLabel       matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        TransferButton              matlab.ui.control.StateButton
        Button_5                    matlab.ui.control.StateButton
        Button_4                    matlab.ui.control.StateButton
        Button_3                    matlab.ui.control.StateButton
        norbitsEditField            matlab.ui.control.NumericEditField
        norbitsEditFieldLabel       matlab.ui.control.Label
        AnimationButton             matlab.ui.control.Button
        StateButton                 matlab.ui.control.Button
        ConstansButton              matlab.ui.control.Button
        CoordinatesButton           matlab.ui.control.Button
        ConversionsPanel            matlab.ui.container.Panel
        RunButton                   matlab.ui.control.Button
        PasteintoConstantsCheckBox  matlab.ui.control.CheckBox
        XEditField                  matlab.ui.control.NumericEditField
        XEditFieldLabel             matlab.ui.control.Label
        ButtonGroup                 matlab.ui.container.ButtonGroup
        KeptoCarButton              matlab.ui.control.RadioButton
        CartoKepButton              matlab.ui.control.RadioButton
        VzEditField                 matlab.ui.control.NumericEditField
        VzEditFieldLabel            matlab.ui.control.Label
        VyEditField                 matlab.ui.control.NumericEditField
        VyEditField_2Label          matlab.ui.control.Label
        VxEditField                 matlab.ui.control.NumericEditField
        VxEditFieldLabel            matlab.ui.control.Label
        ZEditField                  matlab.ui.control.NumericEditField
        ZEditFieldLabel             matlab.ui.control.Label
        YEditField                  matlab.ui.control.NumericEditField
        YEditFieldLabel             matlab.ui.control.Label
        thEditField                 matlab.ui.control.NumericEditField
        thLabel                     matlab.ui.control.Label
        eEditField                  matlab.ui.control.NumericEditField
        eEditFieldLabel             matlab.ui.control.Label
        OMEditField                 matlab.ui.control.NumericEditField
        OMLabel                     matlab.ui.control.Label
        iEditField                  matlab.ui.control.NumericEditField
        iLabel                      matlab.ui.control.Label
        omEditField                 matlab.ui.control.NumericEditField
        omLabel                     matlab.ui.control.Label
        aKmEditField                matlab.ui.control.NumericEditField
        aKmEditFieldLabel           matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        start_counter = 0;
        result1 = struct('r',[],'D_v',[0 0 0]','D_t',[],'v',[],'th',[]);
        result2 = struct('r',[],'D_v',[0 0 0]','D_t',[],'v',[],'th',[]);
        result3 = struct('r',[],'D_v',[0 0 0]','D_t',[],'v',[],'th',[]);
        result4 = struct('r',[],'D_v',[0 0 0]','D_t',[],'v',[],'th',[]);
        result5 = struct('r',[],'D_v',[0 0 0]','D_t',[],'v',[],'th',[]);
        result6 = struct('r',[],'D_v',[0 0 0]','D_t',[],'v',[],'th',[]);
        
    end
    
    properties (Access = private)
   
    end
    
    methods (Access = private)


        function animation_plot_orbit(app,r,vel,theta)
            try
            Terra3d(app,app.UIAxes);
            hold(app.UIAxes,"on");
            X = r(1,:); Y = r(2,:); Z = r(3,:);
            plot3(app.UIAxes,X,Y,Z,'Color',"[0.25, 0.25, 0.25]",'LineStyle',':','LineWidth',0.2);
            grid(app.UIAxes,"on");
            
            % Define an indefinite plot
            v = ([45 30 30]);
            h = plot3(app.UIAxes,nan,nan,nan,'or','MarkerFaceColor',"#77AC30",'MarkerEdgeColor',"#77AC30",'MarkerSize',10);
            traj = plot3(app.UIAxes,nan,nan,nan,'Color',"#A2142F");
            view(app.UIAxes,v);
            % Define the step animation
            step_animation = 2;
            % Define the moving point
            %for j = 1 : app.norbitsEditField.Value
            j = 0;
            
                while j < length(X) - 1
                    j = j + step_animation;
                    set(traj,'XData',X(1:j),'YData',Y(1:j),'ZData',Z(1:j));
                    hold(app.UIAxes,"on");
                    set(h,'XData',X(j),'YData',Y(j),'ZData',Z(j));
                    drawnow
                    
                    app.XEditField.Value = X(j);
                    app.YEditField.Value = Y(j);
                    app.ZEditField.Value = Z(j);
                    app.VxEditField.Value = vel(1,j);
                    app.VyEditField.Value = vel(2,j);
                    app.VzEditField.Value = vel(3,j);
                    app.thEditField.Value = rad2deg(theta(j))-floor(rad2deg(theta(j))/360)*360;
                    
                    stopandplay_button(app,j,step_animation,X,Y,Z,vel,theta,traj,h);
                    
                    pause(app.Delta_thEditField.Value/100);
                end
            catch
                return
            end
            
        end
        
        function Terra3d(app,ax,Rt)


            % plotta la terra in 3d con il suo raggio nell origine degli assi
            % basta richiamare la funzione
            hold(ax,"on")
            %{
            if nargin==2
                Rt=6378;
            end
            %}
            if app.Addtransfer_biellipticCheckBox_4 == 1 || ...
                    app.Addtransfer_change_planeCheckBox == 1 || ...
                    app.Addtransfer_ellipticalCheckBox_3 == 1 || ...
                    app.Addtransfer_periapsisCheckBox_2 == 1
                return
            end
            %{
            load('topo.mat','topo','topomap1');
            %whos topo topomap1
            contour(ax,0:359,-89:90,topo,[0 0],'b')
            axis(ax,"equal")
             box (ax,"on")
             set(gca,'XLim',[0 360],'YLim',[-90 90], ...
                 'XTick',[0 60 120 180 240 300 360], ...
                 'Ytick',[-90 -60 -30 0 30 60 90]);
            hold(ax,"on")
            image(ax,[0 360],[-90 90],topo,'CDataMapping', 'scaled');
            colormap(ax,topomap1)
            [x,y,z] = sphere(100);
             cla reset
             axis square off
            props.AmbientStrength = 0.1;
            props.DiffuseStrength = 1;
            props.SpecularColorReflectance = .5;
            props.SpecularExponent = 20;
            props.SpecularStrength = 1;
            props.FaceColor= 'texture';
            props.EdgeColor = 'none';
            props.FaceLighting = 'phong';
            props.Cdata = topo;
            surface(ax,x*Rt,y*Rt,z*Rt,props);
            light(ax,'position',[1 1 1]);
            light(ax,'position',[-1.5 0.5 -0.5], 'color', [.6 .2 .2]);
            %}
            if nargin < 3
               Rt = 6378;                                       % [km]
            end

%%  Load the Earth image from a website
Earth_image = "8k_earth_daymap-2.jpg";
% Earth_image = 'http://upload.wikimedia.org/wikipedia/commons/thumb/c/cd/Land_ocean_ice_2048.jpg/1024px-Land_ocean_ice_2048.jpg';

%% Figure

% Choose the color of the figure background
background_plot = 'w';

% Create the figure
%figure('Color', background_plot);
hold(ax,"on");
grid(ax,"on");

% Set the axes scale equal
axis(ax,"equal");

% Put the axes labels
xlabel(ax,'X [km]');
ylabel(ax,'Y [km]');
zlabel(ax,'Z [km]');

% Set initial view
view(ax,120,30);

%% Create Earth surface as a wireframe

% Define the number of panels to be used to model the sphere 
npanels = 100;  

% Create a 3D meshgrid of the sphere points using the ellipsoid function
[x, y, z] = ellipsoid(0, 0, 0, Rt, Rt, Rt, npanels);

% Create the globe with the surf function
globe = surf(ax,x, y, -z, 'FaceColor', 'none', 'EdgeColor', 'none');

%% Texturemap the globe

% Load Earth image for texture map
cdata = imread(Earth_image);

% Set the transparency of the globe: 1 = opaque, 0 = invisible
alpha = 1; 

% Set the 'FaceColor' to 'texturemap' to apply an image on the globe, and
% specify the image data using the 'CData' property with the data loaded 
% from the image. Finally, set the transparency and remove the edges.
set(globe, 'FaceColor', 'texturemap', 'CData', cdata, 'FaceAlpha', alpha, 'EdgeColor', 'none');

                        
        end
        
        function transfer_elliptic_plot(app,r1,r2,r3,vel1,vel2,vel3,th1,tht,th3,Kep1,Kept,Kep3,D_v1_car,D_v2_car)
            ai = Kep1(1); ei = Kep1(2); om_i = Kep1(3); 
            at = Kept(1); et = Kept(2); om_t = Kept(3); 
            af = Kep3(1); ef = Kep3(2); om_f = Kep3(3); 
            r = [r1 r2 r3]; vel = [vel1 vel2 vel3]; theta = [th1 tht th3];
            Terra3d(app,app.UIAxes);
            hold(app.UIAxes,"on");
            plot3(app.UIAxes,r(1,:),r(2,:),r(3,:),'Color',"[0.25, 0.25, 0.25]",'LineStyle',':','LineWidth',0.2);
            grid(app.UIAxes,"on");
            
            v = ([45 30 30]);
            h = plot3(app.UIAxes,nan,nan,nan,'or','MarkerFaceColor',"#77AC30",'MarkerEdgeColor',"#77AC30",'MarkerSize',10);
            traj1 = plot3(app.UIAxes,nan,nan,nan,'Color',"#A2142F",'LineWidth',1.2);
            traj2 = plot3(app.UIAxes,nan,nan,nan,'Color',"#77AC30",'LineWidth',1.2);
            traj3 = plot3(app.UIAxes,nan,nan,nan,'Color',"#7E2F8E",'LineWidth',1.2);
            view(app.UIAxes,v);
            % Define the step animation
            step_animation = 1;
            j = 0;
            pos1= length(r1(1,:))+1;
            pos2= length([r1(1,:) r2(1,:)])+1;
            Rt = 6378;
            try
            arr1 = quiver3(app.UIAxes,nan,nan,nan,nan,nan,nan,'b','LineWidth',1.7,'AutoScale','on',...
                                'Color',"#0072BD",'MaxHeadSize',Rt);
            arr2 = quiver3(app.UIAxes,nan,nan,nan,nan,nan,nan,'b','LineWidth',1.7,'AutoScale','on',...
                                'Color',"#0072BD",'MaxHeadSize',Rt);
                while j < length(r(1,:)) 
                    j = j + step_animation;
                    
                    
                    % Update of the constants in all the 3 orbits
                    if j < pos1
                        
                        i = j;
                        X = r1(1,:); Y = r1(2,:); Z = r1(3,:);

                        set(traj1,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                        hold(app.UIAxes,"on");
                        set(h,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j));
                        app.aKmEditField.Value = ai;
                        app.aKmEditField_2.Value = ai;
                        app.eEditField.Value = ei;
                        app.eEditField_2.Value = ei;
                        app.omEditField.Value = rad2deg(om_i);
                        app.omEditField_2.Value = rad2deg(om_i);
                        if th1(1) > 2*pi
                            theta_in = th1(1)-2*pi;
                        else 
                            theta_in = th1(1);
                        end
                        app.th_0EditField.Value = rad2deg(theta_in);
                        if th1(end)>2*pi
                            theta_fin = th1(end)-2*pi;
                        else
                            theta_fin = th1(end);
                        end
                        app.th_fEditField.Value = rad2deg(theta_fin);
                        app.th_fEditField.Value = rad2deg(theta_fin);
                        j = stopandplay_button(app,i,0,step_animation,X,Y,Z,vel,theta,traj1,h);
                    elseif j >= pos1 && j < pos2
                        i = j-pos1+1;
                        X = r2(1,:); Y = r2(2,:); Z = r2(3,:);
                        set(traj2,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                        hold(app.UIAxes,"on");
                        set(h,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j));
                        

                        if j == pos1 || j == pos1-1 || j == pos1+1
                            set(arr1,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j),'UData',D_v1_car(1)*Rt,...
                                'VData',D_v1_car(2)*Rt,'WData',D_v1_car(3)*Rt);
                        end
                        app.aKmEditField.Value = at;
                        app.aKmEditField_2.Value = at;
                        app.eEditField.Value = et;
                        app.eEditField_2.Value = et;
                        app.omEditField.Value = rad2deg(om_t);
                        app.omEditField_2.Value = rad2deg(om_t);
                        
                        if tht(1) > 2*pi
                            theta_in = tht(1)-2*pi;
                        else 
                            theta_in = tht(1);
                        end
                        app.th_0EditField.Value = rad2deg(theta_in);
                        if tht(end)>2*pi
                            theta_fin = tht(end)-2*pi;
                        else
                            theta_fin = tht(end);
                        end
                        app.th_fEditField.Value = rad2deg(theta_fin);
                        app.th_fEditField.Value = rad2deg(theta_fin);
                        i = stopandplay_button(app,i,pos1,step_animation,X,Y,Z,vel,theta,traj2,h);
                        j = pos1 + i;
                        if i < 1
                            j = j - step_animation-2;
                        end
                        if j == pos2
                            set(arr2,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j),'UData',D_v2_car(1)*Rt*5,...
                                'VData',D_v2_car(2)*Rt*5,'WData',D_v2_car(3)*Rt*5);
                            app.aKmEditField.Value = af;
                            app.aKmEditField_2.Value = af;
                            app.eEditField.Value = ef;
                            app.eEditField_2.Value = ef;
                            app.omEditField.Value = rad2deg(om_f);
                            app.omEditField_2.Value = rad2deg(om_f);
                            
                            if th3(1) > 2*pi
                                theta_in = th3(1)-2*pi;
                            else 
                                theta_in = th3(1);
                            end
                            app.th_0EditField.Value = rad2deg(theta_in);
                            if th3(end)>2*pi
                                theta_fin = th3(end)-2*pi;
                            else
                                theta_fin = th3(end);
                            end
                            app.th_fEditField.Value = rad2deg(theta_fin);
                            app.th_fEditField.Value = rad2deg(theta_fin);
                        end
                    elseif j >= pos2
                        i = j-pos2+1;
                        X = r3(1,:); Y = r3(2,:); Z = r3(3,:);
      
                        set(traj3,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                        hold(app.UIAxes,"on");
                        set(h,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j));
                        app.aKmEditField.Value = af;
                        app.aKmEditField_2.Value = af;
                        app.eEditField.Value = ef;
                        app.eEditField_2.Value = ef;
                        app.omEditField.Value = rad2deg(om_f);
                        app.omEditField_2.Value = rad2deg(om_f);
                        
                        if th3(1) > 2*pi
                            theta_in = th3(1)-2*pi;
                        else 
                            theta_in = th3(1);
                        end
                        app.th_0EditField.Value = rad2deg(theta_in);
                        if th3(end)>2*pi
                            theta_fin = th3(end)-2*pi;
                        else
                            theta_fin = th3(end);
                        end
                        app.th_fEditField.Value = rad2deg(theta_fin);
                        app.th_fEditField.Value = rad2deg(theta_fin);
                        i = stopandplay_button(app,i,pos2,step_animation,X,Y,Z,vel,theta,traj3,h);
                        j = i + pos2;
                        if i < 1
                            j = j - step_animation - 2;
                        end
                    end
                    app.XEditField.Value = r(1,j);
                    app.YEditField.Value = r(2,j);
                    app.ZEditField.Value = r(3,j);
                    app.VxEditField.Value = vel(1,j);
                    app.VyEditField.Value = vel(2,j);
                    app.VzEditField.Value = vel(3,j);
                    app.thEditField.Value = rad2deg(theta(j))-floor(rad2deg(theta(j))/360)*360;


                    drawnow;
                    %pause(app.Delta_thEditField.Value/10);

                end
            catch

                return
            end
        end


        function transfer_bielliptic_plot(app,r1,r2,r3,r4,vel1,vel2,vel3,vel4,th1,tht1,tht2,th4,Kep1,Kept1,Kept2,Kep4,D_v1_car,D_v2_car,D_v3_car)
            ai = Kep1(1); ei = Kep1(2); om_i = Kep1(3); 
            at1 = Kept1(1); et1 = Kept1(2); om_t1 = Kept1(3); 
            at2 = Kept2(1); et2 = Kept2(2); om_t2 = Kept2(3); 
            af = Kep4(1); ef = Kep4(2); om_f = Kep4(3); 
            r = [r1 r2 r3 r4]; vel = [vel1 vel2 vel3 vel4]; theta = [th1 tht1 tht2 th4];
            Terra3d(app,app.UIAxes);
            hold(app.UIAxes,"on");
            
            plot3(app.UIAxes,r(1,:),r(2,:),r(3,:),'Color',"[0.25, 0.25, 0.25]",'LineStyle','--','LineWidth',0.2);
            grid(app.UIAxes,"on");
            
            v = ([45 30 30]);
            h = plot3(app.UIAxes,nan,nan,nan,'or','MarkerFaceColor',"#77AC30",'MarkerEdgeColor',"#77AC30",'MarkerSize',10);
            traj1 = plot3(app.UIAxes,nan,nan,nan,'Color',"#A2142F",'LineWidth',1.2);
            traj2 = plot3(app.UIAxes,nan,nan,nan,'Color',"#77AC30",'LineWidth',1.2);
            traj3 = plot3(app.UIAxes,nan,nan,nan,'Color',"#EDB120",'LineWidth',1.2);
            traj4 = plot3(app.UIAxes,nan,nan,nan,'Color',"#7E2F8E",'LineWidth',1.2);
            view(app.UIAxes,v);
            % Define the step animation
            step_animation = 1;
            j = 0;
            pos1= length(r1(1,:))+1;
            pos2= length([r1(1,:) r2(1,:)])+1;
            pos3 = length([r1(1,:) r2(1,:) r3(1,:)])+1;
            Rt = 6378;
            %try
            arr1 = quiver3(app.UIAxes,nan,nan,nan,nan,nan,nan,'b','LineWidth',1.7,'AutoScale','on',...
                                'Color',"#0072BD",'MaxHeadSize',Rt);
            arr2 = quiver3(app.UIAxes,nan,nan,nan,nan,nan,nan,'b','LineWidth',1.7,'AutoScale','on',...
                                'Color',"#0072BD",'MaxHeadSize',Rt);
            arr3 = quiver3(app.UIAxes,nan,nan,nan,nan,nan,nan,'b','LineWidth',1.7,'AutoScale','on',...
                                'Color',"#0072BD",'MaxHeadSize',Rt);
            
                while j < size(r,2) - 1
                    j = j + step_animation;
                    
                    
                    % Update of the constants in all the 3 orbits
                    if j < pos1
                        i = j;
                        X = r1(1,:); Y = r1(2,:); Z = r1(3,:);

                        set(traj1,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                        hold(app.UIAxes,"on");
                        set(h,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j));
                        app.aKmEditField.Value = ai;
                        app.aKmEditField_2.Value = ai;
                        app.eEditField.Value = ei;
                        app.eEditField_2.Value = ei;
                        app.omEditField.Value = rad2deg(om_i);
                        app.omEditField_2.Value = rad2deg(om_i);
                        if th1(1) > 2*pi
                            theta_in = th1(1)-2*pi;
                        else 
                            theta_in = th1(1);
                        end
                        app.th_0EditField.Value = rad2deg(theta_in);
                        if th1(end)>2*pi
                            theta_fin = th1(end)-2*pi;
                        else
                            theta_fin = th1(end);
                        end
                        app.th_fEditField.Value = rad2deg(theta_fin);
                        app.th_fEditField.Value = rad2deg(theta_fin);
                        j = stopandplay_button(app,i,0,step_animation,X,Y,Z,vel,theta,traj1,h);
                    elseif j >= pos1 && j < pos2
                        i = j-pos1+1;
                        X = r2(1,:); Y = r2(2,:); Z = r2(3,:);
                        set(traj2,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                        hold(app.UIAxes,"on");
                        set(h,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j));
                        

                        if j == pos1 || j == pos1-1 || j == pos1+1
                            set(arr1,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j),'UData',D_v1_car(1)*Rt*5,...
                                'VData',D_v1_car(2)*Rt*5,'WData',D_v1_car(3)*Rt*5);
                        end
                        app.aKmEditField.Value = at1;
                        app.aKmEditField_2.Value = at1;
                        app.eEditField.Value = et1;
                        app.eEditField_2.Value = et1;
                        app.omEditField.Value = rad2deg(om_t1);
                        app.omEditField_2.Value = rad2deg(om_t1);
                        
                        if tht1(1) > 2*pi
                            theta_in = tht1(1)-2*pi;
                        else 
                            theta_in = tht1(1);
                        end
                        app.th_0EditField.Value = rad2deg(theta_in);
                        if tht1(end)>2*pi
                            theta_fin = tht1(end)-2*pi;
                        else
                            theta_fin = tht1(end);
                        end
                        app.th_fEditField.Value = rad2deg(theta_fin);
                        app.th_fEditField.Value = rad2deg(theta_fin);
                        i = stopandplay_button(app,i,pos1,step_animation,X,Y,Z,vel,theta,traj2,h);
                        j = pos1 + i;
                        if i < 1
                            j = j - step_animation-2;
                        end
                    elseif j >= pos2 && j < pos3
                        i = j-pos2+1;
                        X = r3(1,:); Y = r3(2,:); Z = r3(3,:);
      
                        set(traj3,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                        hold(app.UIAxes,"on");
                        set(h,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j));
                        
                        if j == pos2 || j == pos2-1 || j == pos2+1
                            set(arr2,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j),'UData',D_v2_car(1)*Rt*5,...
                                'VData',D_v2_car(2)*Rt*5,'WData',D_v2_car(3)*Rt*5);
                        end
                        app.aKmEditField.Value = at2;
                        app.aKmEditField_2.Value = at2;
                        app.eEditField.Value = et2;
                        app.eEditField_2.Value = et2;
                        app.omEditField.Value = rad2deg(om_t2);
                        app.omEditField_2.Value = rad2deg(om_t2);
                        
                        if tht2(1) > 2*pi
                            theta_in = tht2(1)-2*pi;
                        else 
                            theta_in = tht2(1);
                        end
                        app.th_0EditField.Value = rad2deg(theta_in);
                        if tht2(end)>2*pi
                            theta_fin = tht2(end)-2*pi;
                        else
                            theta_fin = tht2(end);
                        end
                        app.th_fEditField.Value = rad2deg(theta_fin);
                        app.th_fEditField.Value = rad2deg(theta_fin);
                        i = stopandplay_button(app,i,pos2,step_animation,X,Y,Z,vel,theta,traj3,h);
                        j = i + pos2;
                        if i < 1
                            j = j - step_animation - 2;
                        end
                        if j == pos3
                            
                            set(arr3,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j),'UData',D_v3_car(1)*Rt*5,...
                                'VData',D_v3_car(2)*Rt*5,'WData',D_v3_car(3)*Rt*5);
                            app.aKmEditField.Value = af;
                            app.aKmEditField_2.Value = af;
                            app.eEditField.Value = ef;
                            app.eEditField_2.Value = ef;
                            app.omEditField.Value = rad2deg(om_f);
                            app.omEditField_2.Value = rad2deg(om_f);
                            
                            if th4(1) > 2*pi
                                theta_in = th4(1)-2*pi;
                            else 
                                theta_in = th4(1);
                            end
                            app.th_0EditField.Value = rad2deg(theta_in);
                            if th4(end)>2*pi
                                theta_fin = th4(end)-2*pi;
                            else
                                theta_fin = th4(end);
                            end
                            app.th_fEditField.Value = rad2deg(theta_fin);
                            app.th_fEditField.Value = rad2deg(theta_fin);
                        end
                    elseif j >= pos3
                        i = j-pos3+1;
                       
                        X = r4(1,:); Y = r4(2,:); Z = r4(3,:);
                        set(traj4,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                        hold(app.UIAxes,"on");
                        set(h,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j));
                        app.aKmEditField.Value = af;
                        app.aKmEditField_2.Value = af;
                        app.eEditField.Value = ef;
                        app.eEditField_2.Value = ef;
                        app.omEditField.Value = rad2deg(om_f);
                        app.omEditField_2.Value = rad2deg(om_f);
                        
                        if th4(1) > 2*pi
                            theta_in = th4(1)-2*pi;
                        else 
                            theta_in = th4(1);
                        end
                        app.th_0EditField.Value = rad2deg(theta_in);
                        if th4(end)>2*pi
                            theta_fin = th4(end)-2*pi;
                        else
                            theta_fin = th4(end);
                        end
                        app.th_fEditField.Value = rad2deg(theta_fin);
                        app.th_fEditField.Value = rad2deg(theta_fin);
                        i = stopandplay_button(app,i,pos3,step_animation,X,Y,Z,vel,theta,traj4,h);
                        j = i + pos3;
                        if i < 1
                            j = j - step_animation - 2;
                        end
                    end
                    
                    app.XEditField.Value = r(1,j);
                    app.YEditField.Value = r(2,j);
                    app.ZEditField.Value = r(3,j);
                    app.VxEditField.Value = vel(1,j);
                    app.VyEditField.Value = vel(2,j);
                    app.VzEditField.Value = vel(3,j);
                    app.thEditField.Value = rad2deg(theta(j))-floor(rad2deg(theta(j))/360)*360;
                    
                    
                    drawnow;
                end
            %catch
                
            %       return
               
            %end
        end       
        
        function [rat1,rpt1,rat2,rpt2,tht1,tht2,om_t1,om_t2] = bielliptic_check(app, rb, rai, rpi, raf, rpf, om_i, om_f)
            if app.perigeButton.Value == 1 
                ai = (rai+rpi)/2; af = (raf+rpf)/2;
                if ai < af
                    if rb > rpi
                        om_t1 = om_i;
                        rat1 = rb; rpt1 = rpi;
                        tht1_i = 0; tht1_f = pi;
                    else
                        om_t1 = om_i + pi;
                        rpt1 = rb; rat1 = rpi;
                        tht1_i = pi; tht1_f = 2*pi;
                    end
                    if rb > rpf
                        om_t2 = om_i;
                        rat2 = rb; rpt2 = rpf;
                        tht2_i = pi; tht2_f = 2*pi;
                    else
                        om_t2 = om_i + pi;
                        rpt2 = rb; rat2 = rpf;
                        tht2_i = 0; tht2_f = pi;
                    end
                else
                    if rb > rpi
                        om_t1 = om_i;
                        rpt1 = rpi; rat1 = rb;
                        tht1_i = 0; tht1_f = pi;
                    else
                        om_t1 = om_i + pi;
                        rpt1 = rb; rat1 = rpi;
                        tht1_i = pi; tht1_f = 2*pi;
                    end
                    if rb > rpf
                        om_t2 = om_i;
                        rpt2 = rpf; rat2 = rb;
                        tht2_i = pi; tht2_f = 2*pi;
                    else
                        om_t2 = om_i + pi;
                        rpt2 = rb; rat2 = rpf;
                        tht2_i = 0; tht2_f = pi;
                    end
                end
            elseif app.apogeButton.Value == 1
                ai = (rai+rpi)/2; af = (raf+rpf)/2;
                if ai > af
                    if rb > rai
                        om_t1 = om_i + pi;
                        rpt1 = rai; rat1 = rb;
                        tht1_i = 0; tht1_f = pi;
                    else
                        om_t1 = om_i;
                        rpt1 = rb; rat1 = rai;
                        tht1_i = pi; tht1_f = 2*pi;
                    end
                    if rb > raf
                        om_t2 = om_i + pi;
                        rpt1 = rb; rat1 = rai;
                        tht2_i = pi; tht2_f = 2*pi;
                    else
                        om_t2 = om_i + pi;
                        rpt2 = raf; rat2 = rb;
                        tht2_i = pi; tht2_f = 2*pi;
                    end
                else
                    if rb > rai
                        om_t1 = om_i + pi;
                        rpt1 = rai; rat1 = rb;
                        tht1_i = 0; tht1_f = pi;
                    else
                        om_t1 = om_i;
                        rpt1 = rb; rat1 = rai;
                        tht1_i = pi; tht1_f = 2*pi;
                    end
                    if rb > raf
                        om_t2 = om_i + pi;
                        rat2 = rb; rpt2 = raf;
                        tht2_i = pi; tht2_f = 2*pi;
                    else
                        om_t2 = om_i;
                        rat2 = raf; rpt2 = rb;
                        tht2_i = 0; tht2_f = pi;
                    end
                end    
            end
            tht1 = [tht1_i tht1_f]; tht2 = [tht2_i tht2_f];
        end
        
 function j_stop = stopandplay_button(app,j_stop,pos,step_animation,X_stop,Y_stop,Z_stop,vel_stop,theta,traj_flag,h)
            
     l = size(X_stop,2);
            while app.Button_3.Value == 1 && j_stop <= l-1
                pause(0.01);
                    while app.Button_4.Value == 1 
                        j_stop = j_stop - step_animation;
                        if j_stop == l || j_stop < 1
                            return
                            %j_stop = j_stop+step_animation;
                            % app.ButtonGroup_3.Value = 0;
                            % app.ButtonGroup_4.Value = 0;
                            % app.ButtonGroup_5.Value = 0;
                        end
                        pause(0.01);
                        app.XEditField.Value = X_stop(j_stop);
                        app.YEditField.Value = Y_stop(j_stop);
                        app.ZEditField.Value = Z_stop(j_stop);
                        app.VxEditField.Value = vel_stop(1,j_stop+pos);
                        app.VyEditField.Value = vel_stop(2,j_stop+pos);
                        app.VzEditField.Value = vel_stop(3,j_stop+pos);
                        app.thEditField.Value = rad2deg(theta(j_stop+pos))-floor(rad2deg(theta(j_stop+pos))/360)*360;
                        set(traj_flag,'XData',X_stop(1:j_stop),'YData',Y_stop(1:j_stop),'ZData',Z_stop(1:j_stop));
                        hold(app.UIAxes,"on");
                        set(h,'XData',X_stop(j_stop),'YData',Y_stop(j_stop),'ZData',Z_stop(j_stop));
                        drawnow;
                    end
                    while app.Button_5.Value == 1
                        if j_stop >= l
                            return
                        end
                        j_stop = j_stop + step_animation;
                        pause(0.01);
                        app.XEditField.Value = X_stop(j_stop);
                        app.YEditField.Value = Y_stop(j_stop);
                        app.ZEditField.Value = Z_stop(j_stop);
                        app.VxEditField.Value = vel_stop(1,j_stop+pos);
                        app.VyEditField.Value = vel_stop(2,j_stop+pos);
                        app.VzEditField.Value = vel_stop(3,j_stop+pos);
                        app.thEditField.Value = rad2deg(theta(j_stop+pos))-floor(rad2deg(theta(j_stop+pos))/360)*360;
                        set(traj_flag,'XData',X_stop(1:j_stop),'YData',Y_stop(1:j_stop),'ZData',Z_stop(1:j_stop));
                        hold(app.UIAxes,"on");
                        set(h,'XData',X_stop(j_stop),'YData',Y_stop(j_stop),'ZData',Z_stop(j_stop));
                        drawnow;
                    end
                
                app.XEditField.Value = X_stop(j_stop);
                app.YEditField.Value = Y_stop(j_stop);
                app.ZEditField.Value = Z_stop(j_stop);
                app.VxEditField.Value = vel_stop(1,j_stop+pos);
                app.VyEditField.Value = vel_stop(2,j_stop+pos);
                app.VzEditField.Value = vel_stop(3,j_stop+pos);
                app.thEditField.Value = rad2deg(theta(j_stop+pos))-floor(rad2deg(theta(j_stop+pos))/360)*360;
                set(traj_flag,'XData',X_stop(1:j_stop),'YData',Y_stop(1:j_stop),'ZData',Z_stop(1:j_stop));
                hold(app.UIAxes,"on");
                set(h,'XData',X_stop(j_stop),'YData',Y_stop(j_stop),'ZData',Z_stop(j_stop));
                drawnow;
            
            end
            
        
    end
    
        
    function transfer_periapsis_plot(app,r1,r2,vel1,vel2,th1,th2,Kep1,Kep2,D_v1_car)
            ai = Kep1(1); ei = Kep1(2); om_i = Kep1(3); 
            af = Kep2(1); ef = Kep2(2); om_f = Kep2(3); 
            r = [r1 r2]; vel = [vel1 vel2]; theta = [th1 th2];
            Terra3d(app,app.UIAxes);
            hold(app.UIAxes,"on");
            plot3(app.UIAxes,r(1,:),r(2,:),r(3,:),'Color',"[0.25, 0.25, 0.25]",'LineStyle',':','LineWidth',0.2);
            grid(app.UIAxes,"on");
            
            v = ([45 30 30]);
            h = plot3(app.UIAxes,nan,nan,nan,'or','MarkerFaceColor',"#77AC30",'MarkerEdgeColor',"#77AC30",'MarkerSize',10);
            traj1 = plot3(app.UIAxes,nan,nan,nan,'Color',"#A2142F",'LineWidth',1.2);
            traj2 = plot3(app.UIAxes,nan,nan,nan,'Color',"#77AC30",'LineWidth',1.2);
            view(app.UIAxes,v);
            % Define the step animation
            step_animation = 1;
            j = 0;
            pos1= length(r1(1,:))+1;
            Rt = 6378;
            %try
            arr1 = quiver3(app.UIAxes,nan,nan,nan,nan,nan,nan,'b','LineWidth',1.7,'AutoScale','on',...
                                'Color',"#0072BD",'MaxHeadSize',Rt);
                while j < length(r(1,:)) - 1
                    j = j + step_animation;

                    % Update of the constants in all the 3 orbits
                    if j < pos1
                        
                        i = j;
                        X = r1(1,:); Y = r1(2,:); Z = r1(3,:);

                        set(traj1,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                        hold(app.UIAxes,"on");
                        set(h,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j));
                        app.aKmEditField.Value = ai;
                        app.aKmEditField_2.Value = ai;
                        app.eEditField.Value = ei;
                        app.eEditField_2.Value = ei;
                        app.omEditField.Value = rad2deg(om_i);
                        app.omEditField_2.Value = rad2deg(om_i);
                        app.th_0EditField.Value = 0;
                        
                        app.th_fEditField.Value = rad2deg(th1(1));
                        app.th_fEditField.Value = rad2deg(th1(end));
                        j = stopandplay_button(app,i,0,step_animation,X,Y,Z,vel,theta,traj1,h);
                        if j == pos1
                            set(arr1,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j),'UData',D_v1_car(1)*Rt/2,...
                                'VData',D_v1_car(2)*Rt/2,'WData',D_v1_car(3)*Rt/2);
                        app.aKmEditField.Value = af;
                        app.aKmEditField_2.Value = af;
                        app.eEditField.Value = ef;
                        app.eEditField_2.Value = ef;
                        app.omEditField.Value = rad2deg(om_f);
                        app.omEditField_2.Value = rad2deg(om_f);
                        
                        app.th_0EditField.Value = rad2deg(th2(1));
                        app.th_fEditField.Value = rad2deg(th2(end));
                        end
                    else
                        i = j-pos1+1;
                        X = r2(1,:); Y = r2(2,:); Z = r2(3,:);
                        set(traj2,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                        hold(app.UIAxes,"on");
                        set(h,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j));

                        app.aKmEditField.Value = af;
                        app.aKmEditField_2.Value = af;
                        app.eEditField.Value = ef;
                        app.eEditField_2.Value = ef;
                        app.omEditField.Value = rad2deg(om_f);
                        app.omEditField_2.Value = rad2deg(om_f);
                        
                        app.th_0EditField.Value = rad2deg(th2(1));
                        app.th_fEditField.Value = rad2deg(th2(end));
                        i = stopandplay_button(app,i,pos1,step_animation,X,Y,Z,vel,theta,traj2,h);
                        j = pos1 + i;
                        if i < 1
                            j = j - step_animation-2;
                        end
                   
                    end
                    app.XEditField.Value = r(1,j);
                    app.YEditField.Value = r(2,j);
                    app.ZEditField.Value = r(3,j);
                    app.VxEditField.Value = vel(1,j);
                    app.VyEditField.Value = vel(2,j);
                    app.VzEditField.Value = vel(3,j);
                    app.thEditField.Value = rad2deg(theta(j))-floor(rad2deg(theta(j))/360)*360;


                    drawnow;
                    %pause(app.Delta_thEditField.Value/10);

                end
            %catch
                %return
            %end
        end
        
        function plane_change_plot(app,r1,r2,vel1,vel2,th1,th2,Kep1,Kep2)
            ai = Kep1(1); ei = Kep1(2); i_i = Kep1(3); OM_i = Kep1(4); om_i = Kep1(5); 
            af = Kep2(1); ef = Kep2(2); i_f = Kep2(3); OM_f = Kep2(4); om_f = Kep2(5); 
            D_v_car = vel2(:,1)-vel1(:,end);
            r = [r1 r2]; vel = [vel1 vel2]; theta = [th1 th2];
            Terra3d(app,app.UIAxes);
            hold(app.UIAxes,"on");
            plot3(app.UIAxes,r(1,:),r(2,:),r(3,:),'Color',"[0.25, 0.25, 0.25]",'LineStyle',':','LineWidth',0.2);
            grid(app.UIAxes,"on");
            
            v = ([45 30 30]);
            h = plot3(app.UIAxes,nan,nan,nan,'or','MarkerFaceColor',"#77AC30",'MarkerEdgeColor',"#77AC30",'MarkerSize',10);
            traj1 = plot3(app.UIAxes,nan,nan,nan,'Color',"#A2142F",'LineWidth',1.2);
            traj2 = plot3(app.UIAxes,nan,nan,nan,'Color',"#7E2F8E",'LineWidth',1.2);
            view(app.UIAxes,v);
            % Define the step animation
            step_animation = 1;
            j = 0;
            pos1= length(r1(1,:))+1;
            Rt = 6378;
            try
            arr1 = quiver3(app.UIAxes,nan,nan,nan,nan,nan,nan,'b','LineWidth',1.7,'AutoScale','on',...
                                'Color',"#0072BD",'MaxHeadSize',Rt);
                while j < length(r(1,:)) - 1
                    j = j + step_animation;
                    
                    
                    % Update of the constants in all the 3 orbits
                    if j < pos1
                        
                        i = j;
                        X = r1(1,:); Y = r1(2,:); Z = r1(3,:);

                        set(traj1,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                        hold(app.UIAxes,"on");
                        set(h,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j));
                        app.aKmEditField.Value = ai;
                        app.aKmEditField_2.Value = ai;
                        app.eEditField.Value = ei;
                        app.eEditField_2.Value = ei;
                        app.iEditField.Value = rad2deg(i_i);
                        app.iEditField_2.Value = rad2deg(i_i);
                        app.OMEditField.Value = rad2deg(OM_i);
                        app.OMEditField_2.Value = rad2deg(OM_i);
                        app.omEditField.Value = rad2deg(om_i);
                        app.omEditField_2.Value = rad2deg(om_i);
                        
                        app.th_0EditField.Value = rad2deg(th1(1));
                        app.th_fEditField.Value = rad2deg(th1(end));
                        j = stopandplay_button(app,i,0,step_animation,X,Y,Z,vel,theta,traj1,h);
                    elseif j >= pos1
                        i = j-pos1+1;
                        X = r2(1,:); Y = r2(2,:); Z = r2(3,:);
                        set(traj2,'XData',X(1:i),'YData',Y(1:i),'ZData',Z(1:i));
                        hold(app.UIAxes,"on");
                        set(h,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j));
                        

                        if j == pos1 || j == pos1-1 || j == pos1+1
                            set(arr1,'XData',r(1,j),'YData',r(2,j),'ZData',r(3,j),'UData',D_v_car(1)*Rt/2,...
                                'VData',D_v_car(2)*Rt/2,'WData',D_v_car(3)*Rt/2);
                        end
                        app.aKmEditField.Value = af;
                        app.aKmEditField_2.Value = af;
                        app.eEditField.Value = ef;
                        app.eEditField_2.Value = ef;
                        app.iEditField.Value = rad2deg(i_f);
                        app.iEditField_2.Value = rad2deg(i_f);
                        app.OMEditField.Value = rad2deg(OM_f);
                        app.OMEditField_2.Value = rad2deg(OM_f);
                        app.omEditField.Value = rad2deg(om_f);
                        app.omEditField_2.Value = rad2deg(om_f);
                        
                        app.th_0EditField.Value = rad2deg(th2(1));
                        app.th_fEditField.Value = rad2deg(th2(end));
                        i = stopandplay_button(app,i,pos1,step_animation,X,Y,Z,vel,theta,traj2,h);
                        j = pos1 + i;
                        if i < 1
                            j = j - step_animation-2;
                        end
                   
                    end
                    app.XEditField.Value = r(1,j);
                    app.YEditField.Value = r(2,j);
                    app.ZEditField.Value = r(3,j);
                    app.VxEditField.Value = vel(1,j);
                    app.VyEditField.Value = vel(2,j);
                    app.VzEditField.Value = vel(3,j);
                    app.thEditField.Value = rad2deg(theta(j))-floor(rad2deg(theta(j))/360)*360;


                    drawnow;
                    %pause(app.Delta_thEditField.Value/10);

                end
            catch

                return
            end
        end
        
        
        function [om_f,th_i,th_f,alpha] = change_plane_check(app,i_i,i_f,OM_i,OM_f,om_i)
           
        
        if OM_f-OM_i >= 0 && i_f - i_i >= 0
            alpha = acos(cos(i_i)*cos(i_f)+sin(i_i)*sin(i_f)*cos(OM_f-OM_i));
            sin_u_i = (sin(i_f)*sin(OM_f-OM_i)/sin(alpha));
            cos_u_i = (cos(alpha)*cos(i_i)-cos(i_f))/(sin(alpha)*sin(i_i));
            if isnan(cos_u_i)
                cos_u_i = inf;
            end
            u_i = atan2(sin_u_i,cos_u_i);
            sin_u_f = (sin(i_i)*sin(OM_f-OM_i)/sin(alpha));
            cos_u_f = (cos(i_i)-cos(alpha)*cos(i_f))/(sin(alpha)*sin(i_f));
            if isnan(cos_u_f)
                cos_u_f = inf;
            end
            u_f = atan2(sin_u_f,cos_u_f);
            if u_i >= om_i 
                th_i = u_i-om_i;
            else
                th_i = 2*pi+(u_i-om_i);
            end
            th_f = th_i;
            if u_f >=  th_f
                om_f = u_f-th_f;
            else
                om_f = 2*pi+(u_f-th_f);
            end
        elseif OM_f-OM_i >= 0 && i_f-i_i < 0
            alpha = acos(cos(i_i)*cos(i_f)+sin(i_i)*sin(i_f)*cos(OM_f-OM_i));
            sin_u_i = (-sin(i_f)*sin(OM_f-OM_i)/sin(alpha));
            cos_u_i = (cos(i_f)-cos(alpha)*cos(i_i))/(sin(alpha)*sin(i_i));
            if isnan(cos_u_i)
                cos_u_i = inf;
            end
            u_i = atan2(sin_u_i,cos_u_i);
            sin_u_f = (-sin(i_i)*sin(OM_f-OM_i)/sin(alpha));
            cos_u_f = (cos(alpha)*cos(i_f)-cos(i_i))/(sin(alpha)*sin(i_f));
            if isnan(cos_u_f)
                cos_u_f = inf;
            end
            u_f = atan2(sin_u_f,cos_u_f);
            if u_i >= om_i
                th_i = u_i - om_i;
            else
                th_i = 2*pi + (u_i-om_i);
            end
            th_f = th_i;
            if u_f >= th_f
                om_f = u_f - th_f;
            else
                om_f = 2*pi + (u_f-th_f);
            end
        elseif OM_f-OM_i <= 0 && i_f-i_i >= 0
            alpha = acos(cos(i_i)*cos(i_f)+sin(i_i)*sin(i_f)*cos(OM_f-OM_i));
            sin_u_i = (sin(i_f)*sin(OM_f-OM_f)/sin(alpha));
            cos_u_i = (cos(alpha)*cos(i_i)-cos(i_f))/(sin(alpha)*sin(i_i));
            if isnan(cos_u_i)
                cos_u_i = inf;
            end
            u_i = atan2(sin_u_i,cos_u_i);
            sin_u_f = (sin(i_i)*sin(OM_f-OM_i)/sin(alpha));
            cos_u_f = (cos(i_i)-cos(alpha)*cos(i_f))/(sin(alpha)*sin(i_f));
            if isnan(cos_u_f)
                cos_u_f = inf;
            end
            u_f = atan2(sin_u_f,cos_u_f);
            if u_i >= om_i
                th_i = u_i - om_i;
            else
                th_i = 2*pi + (u_i- om_i);
            end
            th_f = th_i;
            if u_f >= th_f
                om_f = u_f - th_f;
            else
                om_f = 2*pi + (u_f - th_f);
            end
        elseif OM_f-OM_i <= 0 && i_f-i_i < 0
            alpha = acos(cos(i_i)*cos(i_f)+sin(i_i)*sin(i_f)*cos(OM_f-OM_i));
            sin_u_i = (-sin(i_f)*sin(OM_f-OM_i)/sin(alpha));
            cos_u_i = (cos(i_f)-cos(alpha)*cos(i_i))/(sin(alpha)*sin(i_i));
            if isnan(cos_u_i)
                cos_u_i = inf;
            end
            u_i = atan2(sin_u_i,cos_u_i);
            sin_u_f = (-sin(i_i)*sin(OM_f-OM_i)/sin(alpha));
            cos_u_f = (cos(alpha)*cos(i_f)-cos(i_i))/(sin(alpha)*sin(i_f));
            if isnan(cos_u_f)
                cos_u_f = inf;
            end
            u_f = atan2(sin_u_f,cos_u_f);
            if u_i >= om_i
                th_i = u_i - om_i;
            else
                th_i = 2*pi + (u_i-om_i);
            end
            th_f = th_i;
            if u_f >= th_f
                om_f = u_f-th_f;
            else
                om_f = 2*pi + (u_f - th_f);
            end
        end


        end
        
        function add_Deltas(app,D_v1,D_v2,D_v3,D_t)
            D_v1 = abs(D_v1); D_v2 = abs(D_v2); D_v3 = abs(D_v3);
            if app.start_counter == 1
                app.result1.D_v = app.result1.D_v+D_v1+D_v2+D_v3;
                app.D_vEditField_2.Value = norm(app.result1.D_v);
                app.D_tEditField_1.Value = D_t;
            elseif app.start_counter == 2
                app.result1.D_v = app.result2.D_v+D_v1+D_v2+D_v3;
                app.D_vEditField_3.Value = norm(app.result2.D_v);
                app.D_tEditField_2.Value = D_t;
            elseif app.start_counter == 3
                app.result3.D_v = app.result3.D_v+D_v1+D_v2+D_v3;
                app.D_vEditField_4.Value = norm(app.result3.D_v);
                app.D_tEditField_3.Value = D_t;
            elseif app.start_counter == 4
                app.result4.D_v = app.result4.D_v+D_v1+D_v2+D_v3;
                app.D_vEditField_4.Value = norm(app.result4.D_v);
                app.D_tEditField_4.Value = D_t;
            elseif app.start_counter == 5
                app.result5.D_v = app.result5.D_v+D_v1+D_v2+D_v3;
                app.D_vEditField_4.Value = norm(app.result5.D_v);
                app.D_tEditField_5.Value = D_t;
            elseif app.start_counter == 6
                app.result6.D_v = app.result6.D_v+D_v1+D_v2+D_v3;
                app.D_vEditField_4.Value = norm(app.result6.D_v);
                app.D_tEditField_5.Value = D_t;
            end
            
        end
        
        function add_r(app,r,v,th)
            app.("result"+num2str(app.start_counter)).r = [app.("result"+num2str(app.start_counter)).r r];
            app.("result"+num2str(app.start_counter)).v = [app.("result"+num2str(app.start_counter)).v v];
            app.("result"+num2str(app.start_counter)).th = [app.("result"+num2str(app.start_counter)).th th];
            %{
            if app.start_counter == 1
                app.result1.r =[app.result1.r r];
            elseif app.start_counter == 2
                app.result2.r =[app.result2.r r];
            elseif app.start_counter == 3
                app.result3.r =[app.result3.r r];
            elseif app.start_counter == 4
                app.result4.r =[app.result4.r r];
            elseif app.start_counter == 5
                app.result5.r =[app.result5.r r];
            elseif app.start_counter == 6
                app.result6.r =[app.result6.r r];
            end
            %}
            
        end
        
        function add_descr_transfers(app)
            value = app.StartButton.Value;
            if value == 1
                prompt = {['Description of the transfer n.',num2str(app.start_counter)]};
                dlgtitle = 'Input';
                fieldsize = [3 50];
                definput = {''};
                answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
                app.("TextArea_"+num2str(app.start_counter)).Value = answer;
                %{
                if app.start_counter == 1
                    app.TextArea_1.Value = answer;
                elseif app.start_counter == 2
                    app.TextArea_2.Value = answer;
                elseif app.start_counter == 3
                    app.TextArea_3.Value = answer;
                elseif app.start_counter == 4
                    app.TextArea_4.Value = answer;
                elseif app.start_counter == 5
                    app.TextArea_5.Value = answer;
                elseif app.start_counter == 6
                    app.TextArea_6.Value = answer;
                end
                %}
            end
            
        end
        
       
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % COORDINATES PANEL
            mu = app.muEditField.Value;
            if app.KeptoCarButton.Value == 1
                % Update cartesian coordinates to keplerian
                a = app.aKmEditField.Value;
                e = app.eEditField.Value;
                i = deg2rad(app.iEditField.Value);
                OM = deg2rad(app.OMEditField.Value);
                om = deg2rad(app.omEditField.Value);
                th = deg2rad(app.thEditField.Value);
                [r,v] = kep2car(a,e,i,OM,om,th,mu);
                app.XEditField.Value = r(1);
                app.YEditField.Value = r(2);
                app.ZEditField.Value = r(3);
                app.VxEditField.Value = v(1);
                app.VyEditField.Value = v(2);
                app.VzEditField.Value = v(3);
            
            elseif app.CartoKepButton.Value == 1
                % Update cartesian coordinates to keplerian 
                r = zeros(3,1); v = zeros(3,1);
                r(1) = app.XEditField.Value;
                r(2) = app.YEditField.Value;
                r(3) = app.ZEditField.Value;
                v(1) = app.VxEditField.Value;
                v(2) = app.VyEditField.Value;
                v(3) = app.VzEditField.Value;
                [a,e,i,OM,om,th] = car2kep(r,v,mu);
                app.aKmEditField.Value = a;
                app.eEditField.Value = e;
                app.iEditField.Value = rad2deg(i);
                app.OMEditField.Value = rad2deg(OM);
                app.omEditField.Value = rad2deg(om);
                app.thEditField.Value = rad2deg(th);
                
            end
            % paste coordinates into constants panel, in order to start an
            % animation
            value = app.PasteintoConstantsCheckBox.Value;
            if value == 1
                app.aKmEditField_2.Value = app.aKmEditField.Value;
                app.eEditField_2.Value = app.eEditField.Value;
                app.iEditField_2.Value = app.iEditField.Value;
                app.OMEditField_2.Value = app.OMEditField.Value;
                app.omEditField_2.Value = app.omEditField.Value;
                app.th_fEditField.Value = app.thEditField.Value;
            end
            % update "other constants" in constants panel
            app.EEditField.Value = 0.5*norm(v)^2-app.muEditField.Value/norm(r);
            app.pEditField.Value = app.aKmEditField.Value*(1-app.eEditField.Value^2);
            app.hEditField_2.Value = norm(cross(r,v));
            app.bEditField.Value = app.aKmEditField.Value*sqrt(1-app.eEditField.Value^2);
            app.rpEditField.Value = app.rpEditField.Value/(1+app.EEditField.Value);
            app.raEditField.Value = app.raEditField.Value/(1-app.EEditField.Value);
        end

        % Button pushed function: CoordinatesButton
        function CoordinatesButtonPushed(app, event)
                app.TabGroup.Visible = "off";
                app.ConversionsPanel.Visible = "on";
        end

        % Button pushed function: ConstansButton
        function ConstansButtonPushed(app, event)
                app.ConversionsPanel.Visible = "off";
                app.TabGroup.Visible = "on";
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
        
        end

        % Button pushed function: StateButton
        function StateButtonPushed(app, event)
            % State button is used to do a static plot of the orbit,
            % the function is not created because it would be used just for one
            % time
            cla(app.UIAxes,'reset') 

            tetha = deg2rad(app.th_0EditField.Value):app.Delta_thEditField.Value:deg2rad(app.th_fEditField.Value);
            state_orbit_plot(app.UIAxes,app.aKmEditField_2.Value,...
            app.eEditField_2.Value,...
            deg2rad(app.iEditField_2.Value),...
            deg2rad(app.OMEditField_2.Value),...
            deg2rad(app.omEditField_2.Value),...
            (tetha),...
            app.muEditField.Value);
        end

        % Button pushed function: AnimationButton
        function AnimationButtonPushed(app, event)
            
            cla(app.UIAxes,'reset') 

            theta = deg2rad(app.th_0EditField.Value):app.Delta_thEditField.Value:app.norbitsEditField.Value*deg2rad(app.th_fEditField.Value);
            [r,vel] = kep2car(app.aKmEditField_2.Value,...
                app.eEditField_2.Value,...
                deg2rad(app.iEditField_2.Value),...
                deg2rad(app.OMEditField_2.Value),...
                deg2rad(app.omEditField_2.Value),...
                (theta),...
                app.muEditField.Value);
            if app.start_counter == 0
                animation_plot_orbit(app,r,vel,theta);
            else
                animation_plot_orbit(app,app.("result" + num2str(app.start_counter)).r,...
                    app.("result" + num2str(app.start_counter)).v,app.("result" + num2str(app.start_counter)).th);
            end
        end

        % Value changed function: Button_3
        function Button_3ValueChanged(app, event)
             if app.Button_3.Value == 0
                 app.Button_4.Value = 0;
                 app.Button_5.Value = 0;
             end
        end

        % Value changed function: Button_4
        function Button_4ValueChanged(app, event)
            if app.Button_4.Value == 1
                app.Button_5.Value = 0;
            end
        end

        % Button pushed function: Run_elliptical
        function Run_ellipticalPushed(app, event)
            if app.Addtransfer_ellipticalCheckBox_3.Value == 0
                cla(app.UIAxes,'reset') 
            end
            ei = app.e_iEditField.Value; ef = app.e_fEditField.Value;
            ai = app.a_iEditField.Value; af = app.a_fEditField.Value;
            mu = app.muEditField.Value;
            p1 = ai*(1-ei^2);
            p2 = af*(1-ef^2);
            rp1 = p1/(1+ei); ra1 = p1/(1-ei);
            rp2 = p2/(1+ef); ra2 = p2/(1-ef);
            th0 = deg2rad(app.th_iEditField.Value);
            thf = deg2rad(app.th_fEditField_2.Value);
            if app.apogeapogeButton.Value == 1
                 app.om_fEditField.Value = app.om_iEditField.Value + 180;
                 om_i = deg2rad(app.om_iEditField.Value);
                 om_f = deg2rad(app.om_fEditField.Value);
                 %app.th_iEditField.Value = 180;
                 %app.th_fEditField_2.Value = 180+360;
                if ai <= af
                    om_t = om_f;
                    th1_f = pi;
                    tht_i = 0; tht_f = pi;
                    th2_i = pi;
                else 
                    om_t = om_i;
                    th1_f = pi;
                    tht_i = pi; tht_f = 2*pi;
                    th2_i = pi; 
                end
            at = (ra1+ra2)/2; et = abs(ra2-ra1)/(ra2+ra1); pt = at*(1-et^2);
            D_v1 = sqrt(2*mu*(1/ra1-1/(2*at)))-sqrt(2*mu*(1/ra1-1/(2*ai)));
            D_v2 = sqrt(2*mu*(1/ra2-1/(2*af)))-sqrt(2*mu*(1/ra2-1/(2*at)));
        
           elseif app.perigeperigeButton.Value == 1
                app.om_fEditField.Value = app.om_iEditField.Value + 180;
                om_i = deg2rad(app.om_iEditField.Value);
                om_f = deg2rad(app.om_fEditField.Value);
                %app.th_iEditField.Value = 0;
                %app.th_fEditField_2.Value = 360;
                
                om_t = om_i;
                th1_f = 0;
                tht_i = 0; tht_f = pi;
                th2_i = 0; 
                
                at = (rp1+rp2)/2; et = (rp2-rp1)/(rp2+rp1); pt = at*(1-et^2);
                D_v1 = sqrt(2*mu*(1/rp1-1/(2*at)))-sqrt(2*mu*(1/rp1-1/(2*ai)));
                D_v2 = sqrt(2*mu*(1/rp2-1/(2*af)))-sqrt(2*mu*(1/rp2-1/(2*at)));
            elseif app.apogeperigeButton.Value == 1
                app.om_fEditField.Value = app.om_iEditField.Value;
                om_i = deg2rad(app.om_iEditField.Value);
                om_f = deg2rad(app.om_fEditField.Value);
                %app.th_iEditField.Value = 180;
                %app.th_fEditField_2.Value = 360;
                if ai >= af
                    om_t = om_i;
                    th1_f = pi;
                    tht_i = pi; tht_f = 2*pi;
                    th2_i = 0;
                elseif ai < af
                    om_t = om_i+pi;
                    th1_f = pi;
                    tht_i = 0; tht_f = pi;
                    th2_i = 0;
                
                end
                at = (ra1+rp2)/2; et = abs(ra1-rp2)/(rp2+ra1);
                D_v1 = sqrt(2*mu*(1/ra1-1/(2*at)))-sqrt(2*mu*(1/ra1-1/(2*ai)));
                D_v2 = sqrt(2*mu*(1/rp2-1/(2*af)))-sqrt(2*mu*(1/rp2-1/(2*at)));
                
            elseif app.perigeapogeButton.Value == 1
                app.om_fEditField.Value = app.om_iEditField.Value;
                %app.th_iEditField.Value = 0;
                %app.th_fEditField_2.Value = 180;
                om_i = deg2rad(app.om_iEditField.Value);
                om_f = deg2rad(app.om_fEditField.Value);

                om_t = om_f;
                th1_f = 0;
                tht_i = 0; tht_f = pi;
                th2_i = pi; 
    
                at = (rp1+ra2)/2; et = (ra2-rp1)/(ra2+rp1); pt = at*(1-et^2);
                D_v1 = sqrt(2*mu*(1/rp1-1/(2*at)))-sqrt(2*mu*(1/rp1-1/(2*ai)));
                D_v2 = sqrt(2*mu*(1/ra2-1/(2*af)))-sqrt(2*mu*(1/ra2-1/(2*at)));
            end
            app.a_tEditField.Value=at; app.e_tEditField.Value = et;
            i = deg2rad(app.iEditField_3.Value); OM = deg2rad(app.OMEditField_3.Value);
            app.iEditField_2.Value = rad2deg(i);
            app.OMEditField_2.Value = rad2deg(OM);
            app.iEditField.Value = rad2deg(i);
            app.OMEditField.Value = rad2deg(OM);
            mu = app.muEditField.Value;


            stepTh = pi/180;
            if th0 <= th1_f
                th1 = th0:stepTh:th1_f;
            else
                th1 = th0:stepTh:th1_f+2*pi;
            end
            [r1,v1,thi] = kep2car(ai,ei,i,OM,om_i,th1,mu);

            [rt,vt,th_t] = kep2car(at,et,i,OM,om_t,tht_i:stepTh:tht_f,mu);
            if thf >= th2_i
                th2 = th2_i:stepTh:thf;
            else
                th2 = th2_i:stepTh:2*pi+thf;
            end
            R3 = [cos(OM) sin(OM) 0; -sin(OM) cos(OM) 0; 0 0 1];
            R2 = [1 0 0; 0 cos(i) sin(i); 0 -sin(i) cos(i)];
            R1 = [cos(om_i) sin(om_i) 0; -sin(om_i) cos(om_i) 0; 0 0 1];
            R = R1*R2*R3;
            [r3,v3,thf] = kep2car(af,ef,i,OM,om_f,th2,mu);
            D_v_car1 = D_v1 .* R'* [-sin(tht_i) cos(tht_i) 0]';
            app.D_v1EditField.Value=D_v1;
            vt(:,1) = vt(:,1) + D_v_car1;
            D_v_car2 = D_v2 .* R'* [-sin(tht_f) cos(tht_f) 0]';
            app.D_v2EditField.Value=D_v2;
            v3(:,1) = v3(:,1) + D_v_car2;
            Kep1 = [ai,ei,om_i]; Kept = [at,et,om_t]; Kep3 = [af,ef,om_f];
            D_t = 0;
            if isempty(r3)
                r3 = [r3 rt(:,end)];
                thf = [thf th_t(end)+stepTh];
            end
            if app.start_counter >= 1 && app.Addtransfer_ellipticalCheckBox_3.Value == 1
                %add_descr_transfers(app)
                add_Deltas(app,D_v_car1,D_v_car2,[0 0 0]',D_t)
                add_r(app,[r1 rt r3],[v1 vt v3],[th1 th_t thf])
            end
            transfer_elliptic_plot(app,r1,rt,r3,v1,vt,v3,thi,th_t,thf,Kep1,Kept,Kep3,D_v_car1,D_v_car2);
            
        end

        % Value changed function: useconstantsCheckBox
        function useconstantsCheckBoxValueChanged(app, event)
                        value = app.useconstantsCheckBox.Value;
                        if value == 1
                            app.a_iEditField.Value = app.aKmEditField_2.Value;
                            app.e_iEditField.Value = app.eEditField_2.Value;
                            app.om_iEditField.Value = app.omEditField_2.Value;
                            app.th_iEditField.Value = app.th_fEditField.Value;
                            app.iEditField_3.Value = app.iEditField_2.Value;
                            app.OMEditField_3.Value = app.OMEditField_2.Value;
                        end
        end

        % Value changed function: TransferButton
        function TransferButtonValueChanged(app, event)
            value = app.TransferButton.Value;
            if value == 1
                app.TabGroup2.Visible = "on";
            else
                app.TabGroup2.Visible = "off";
            end
        end

        % Selection changed function: ButtonGroup_2
        function ButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_2.SelectedObject;
            %{
            if app.apogeapogeButton == selectedButton
                 app.om_fEditField.Value = app.om_iEditField.Value + 180;
                 app.th_iEditField.Value = 0;
                 app.th_fEditField_2.Value = 180;
            end
            if app.apogeperigeButton == selectedButton
                app.om_fEditField.Value = app.om_iEditField.Value;
                app.th_iEditField.Value = 180;
                app.th_fEditField_2.Value = 360;
            end
            if app.perigeapogeButton == selectedButton     
                app.om_fEditField.Value = app.om_iEditField.Value;
                app.th_iEditField.Value = 0;
                app.th_fEditField_2.Value = 180;
            end
            if app.perigeperigeButton == selectedButton
                app.om_fEditField.Value = app.om_iEditField.Value + 180;
                app.th_iEditField.Value = 180;
                 app.th_fEditField_2.Value = 360;
            end
            %}
        end

        % Value changed function: a_iEditField, e_iEditField, om_iEditField
        function e_iEditFieldValueChanged(app, event)
            
            if app.apogeapogeButton.Value == 1
                 app.om_fEditField.Value = app.om_iEditField.Value + 180;
                 app.th_iEditField.Value = 0;
                 app.th_fEditField_2.Value = 180;
            end
            if app.apogeperigeButton.Value == 1
                app.om_fEditField.Value = app.om_iEditField.Value;
                app.th_iEditField.Value = 180;
                app.th_fEditField_2.Value = 360;
            end
            if app.perigeapogeButton == 1
                app.om_fEditField.Value = app.om_iEditField.Value;
                app.th_iEditField.Value = 0;
                app.th_fEditField_2.Value = 180;
            end
            if app.perigeperigeButton.Value == 1
                app.om_fEditField.Value = app.om_iEditField.Value + 180;
                app.th_iEditField.Value = 180;
                app.th_fEditField_2.Value = 360;
            end
        end

        % Button down function: ButtonGroup_2
        function ButtonGroup_2ButtonDown(app, event)
            if app.apogeapogeButton.Value == 1
                 app.om_fEditField.Value = app.om_iEditField.Value + 180;
                 app.th_iEditField.Value = 0;
                 app.th_fEditField_2.Value = 180;
            end
        end

        % Value changed function: EllipticalbitangentButton
        function EllipticalbitangentButtonValueChanged(app, event)
            value = app.EllipticalbitangentButton.Value;
            if value == 1
                app.elliptical_bi_panel.Visible = "on";
            elseif value == 0
                app.elliptical_bi_panel.Visible = "off";
            end
        end

        % Value changed function: Delta_thEditField, OMEditField_2, 
        % ...and 7 other components
        function iEditField_2ValueChanged(app, event)
            
            v = [app.VxEditField.Value;app.VyEditField.Value;app.VzEditField.Value];
            r = [app.XEditField.Value;app.YEditField.Value;app.ZEditField.Value];
            app.rpEditField.Value = app.pEditField.Value/(1+app.EEditField.Value);
            app.raEditField.Value = app.pEditField.Value/(1-app.EEditField.Value);
            app.EEditField.Value = 0.5*norm(v)^2-app.muEditField.Value/norm(r);
            app.pEditField.Value = app.aKmEditField.Value*(1-app.eEditField.Value^2);
            app.hEditField_2.Value = norm(cross(r,v));
            app.bEditField.Value = app.aKmEditField.Value*sqrt(1-app.eEditField.Value^2);
        end

        % Button pushed function: Run_bielliptic
        function Run_biellipticPushed(app, event)
            %try
            if app.Addtransfer_biellipticCheckBox_4 == 0
                cla(app.UIAxes,'reset') 
            end
            ei = app.e_iEditField_2.Value; ef = app.e_fEditField_2.Value;
            ai = app.a_iEditField_2.Value; af = app.a_fEditField_2.Value;
            app.om_fEditField_2.Value = app.om_iEditField_2.Value;
            om_i = deg2rad(app.om_iEditField_2.Value); 
            om_f = deg2rad(app.om_fEditField_2.Value);
            th_i = deg2rad(app.th_iEditField_2.Value); 
            th_f = deg2rad(app.th_fEditField_3.Value);
            i = deg2rad(app.iEditField_4.Value); OM = deg2rad(app.OMEditField_4.Value);
            app.iEditField_2.Value = rad2deg(i);
            app.OMEditField_2.Value = rad2deg(OM);
            app.iEditField.Value = rad2deg(i);
            app.OMEditField.Value = rad2deg(OM);
            mu = app.muEditField.Value;
            p1 = ai*(1-ei^2);
            p2 = af*(1-ef^2);
            rp1 = p1/(1+ei); ra1 = p1/(1-ei);
            rp2 = p2/(1+ef); ra2 = p2/(1-ef);
            rb = app.r_bEditField.Value;
            [ra_t1,rp_t1,ra_t2,rp_t2,tht1,tht2,om_t1,om_t2] = bielliptic_check(app, rb, ra1, rp1, ra2, rp2, om_i, om_f);
            if app.perigeButton.Value == 1
                th1_i = th_i; th1_f = 2*pi;
                th4_i = 0; th4_f = 2*pi;
                at1 = (rp_t1+ra_t1)/2; et1 = (ra_t1-rp_t1)/(ra_t1+rp_t1); 
                at2 = (rp_t2+ra_t2)/2; et2 = (ra_t2-rp_t2)/(ra_t2+rp_t2); 
                
                D_v1 = sqrt(2*mu*(1/rp1-1/(2*at1)))-sqrt(2*mu*(1/rp1-1/(2*ai)));
                D_v2 = sqrt(2*mu*(1/rb-1/(2*at2)))-sqrt(2*mu*(1/rb-1/(2*at1)));
                D_v3 = sqrt(2*mu*(1/rp2-1/(2*af)))-sqrt(2*mu*(1/rp2-1/(2*at2)));
            elseif app.apogeButton.Value == 1
                th1_i = th_i; th1_f = 3*pi;
                th4_i = pi; th4_f = 3*pi;
                at1 = (rp_t1+ra_t1)/2; et1 = (ra_t1-rp_t1)/(ra_t1+rp_t1); 
                at2 = (rp_t2+ra_t2)/2; et2 = (ra_t2-rp_t2)/(ra_t2+rp_t2); 
                
                D_v1 = sqrt(2*mu*(1/ra1-1/(2*at1)))-sqrt(2*mu*(1/ra1-1/(2*ai)));
                D_v2 = sqrt(2*mu*(1/rb-1/(2*at2)))-sqrt(2*mu*(1/rb-1/(2*at1)));
                D_v3 = sqrt(2*mu*(1/ra2-1/(2*af)))-sqrt(2*mu*(1/ra2-1/(2*at2)));
            end
            app.a_t1EditField.Value = at1;
            app.a_t2EditField.Value = at2;
            app.D_v1EditField_2.Value = D_v1;
            app.D_v2EditField_2.Value = D_v2;
            app.D_v3EditField.Value = D_v3;
            app.e_t1EditField.Value = et1;
            app.e_t2EditField.Value = et2;
            tht1_i = tht1(1); tht1_f = tht1(2); tht2_i = tht2(1); tht2_f = tht2(2);

            R3 = [cos(OM) sin(OM) 0; -sin(OM) cos(OM) 0; 0 0 1];
            R2 = [1 0 0; 0 cos(i) sin(i); 0 -sin(i) cos(i)];
            R1 = [cos(om_i) sin(om_i) 0; -sin(om_i) cos(om_i) 0; 0 0 1];
            R = R1*R2*R3;

            stepTh = 2*pi/180;
            if th_i < th1_f
                th1 = th_i:stepTh:th1_f;
            else
                th1 = th_i:stepTh:th1_f+2*pi;
            end
            [r1,v1,th1] = kep2car(ai,ei,i,OM,om_i,th1,mu);
            [rt1,vt1,tht1] = kep2car(at1,et1,i,OM,om_t1,tht1_i:stepTh:tht1_f,mu);
            [rt2,vt2,tht2] = kep2car(at2,et2,i,OM,om_t2,tht2_i:stepTh:tht2_f,mu);
            if th_f > th4_i
                th4 = th4_i:stepTh:th_f;
            else
                th4 = th4_i:stepTh:th_f+2*pi;
            end
            [r4,v4,th4] = kep2car(af,ef,i,OM,om_f,th4,mu);
            
            D_v_car1 = D_v1 .* R'* [-sin(th_f) cos(th_f) 0]';
            v1(:,end) = v1(:,end) + D_v_car1;
            
            
            D_v_car2 = D_v2 .* R'* [-sin(tht2_i) cos(tht2_i) 0]';
            vt1(:,end) = vt1(:,end) + D_v_car2;
            
            D_v_car3 = D_v3 .* R'* [-sin(th_f) cos(th_f) 0]';
            vt2(:,end) = vt2(:,end) + D_v_car3;
            
            Kep1 = [ai ei om_i]; Kept1 = [at1 et1 om_t1]; Kept2 = [at2 et2 om_t2]; Kep4 = [af ef om_f];
            
            D_t = 0;
            if app.start_counter >= 1 && app.Addtransfer_biellipticCheckBox_4.Value == 1
                %add_descr_transfers(app)
                add_Deltas(app,D_v_car1,D_v_car2,[0 0 0]',D_t)
                add_r(app,[r1 rt1 rt2 r4],[v1 vt1 vt2 v4],[th1 tht1 tht2 th4])
            end
            if isempty(r4)
                r4 = [r4 rt2(:,end)];
                th4 = [th4 tht2(end)+stepTh];
            end
            transfer_bielliptic_plot(app,r1,rt1,rt2,r4,v1,vt1,vt2,v4,...
                th1,tht1,tht2,th4,Kep1,Kept1,Kept2,Kep4,D_v_car1,D_v_car2,D_v_car3)
            
            %catch 
                % if rb == 0
                %         warndlg("Attention! rb must be a non-zero element")
                % else
                %     return
                % end
            %end


        end

        % Selection changed function: ButtonGroup_3
        function ButtonGroup_3SelectionChanged(app, event)
            selectedButton = app.ButtonGroup_3.SelectedObject;
            if selectedButton == app.perigeButton
                app.th_iEditField_2 = 0;
            elseif selectedButton == app.apogeButton

            end
        end

        % Value changed function: BiellipticButton
        function BiellipticButtonValueChanged(app, event)
            value = app.BiellipticButton.Value;
            if value == 1
                app.bielliptic.Visible = "on";   
            end
        end

        % Value changed function: useconstantsCheckBox_3
        function useconstantsCheckBox_3ValueChanged(app, event)
            value = app.useconstantsCheckBox_3.Value;
            if value == 1
                app.a_iEditField_3.Value = app.aKmEditField_2.Value;
                app.e_iEditField_3.Value = app.eEditField_2.Value;
                app.om_iEditField_3.Value = app.omEditField_2.Value;
                app.th_iEditField_3.Value = app.th_fEditField.Value;
                app.iEditField_5.Value = app.iEditField_2.Value;
                app.OMEditField_5.Value = app.OMEditField_2.Value;
            end
        end

        % Button pushed function: RunButton_4
        function RunButton_4Pushed(app, event)
            %try
            if app.Addtransfer_periapsisCheckBox_2.Value == 0
                cla(app.UIAxes,'reset') 
            else
                app.UIAxes.LineWidth = 0.7;
            end
            hold (app.UIAxes,"on")
            %hold(app.UIAxes,"off")
            ei = app.e_iEditField_3.Value; 
            ai = app.a_iEditField_3.Value; 
            i = deg2rad(app.iEditField_5.Value); 
            OM = deg2rad(app.OMEditField_5.Value);
            om_i = deg2rad(app.om_iEditField_3.Value);
            mu = app.muEditField.Value;
            om_f = deg2rad(app.om_fEditField_3.Value);
            delta_om = om_f-om_i;
            app.iEditField_2.Value = rad2deg(i);
            app.OMEditField_2.Value = rad2deg(OM);
            app.iEditField.Value = rad2deg(i);
            app.OMEditField.Value = rad2deg(OM);
            if delta_om < 0
                delta_om = delta_om + 2*pi;
            end
            stepTh = 2*pi/180;
            th0 = deg2rad(app.th_iEditField_3.Value);
            thf = deg2rad(app.th_fEditField_4.Value);
            if app.pointaButton.Value == 1
                th1_i= delta_om/2;
                th1_f = 2*pi-delta_om/2;
                
                if th0 >= th1_i
                    th1 = th0:stepTh:2*pi + th1_i;
                else
                    th1 = th0:stepTh:th1_i;
                end
                %{
                if thf > th1_f
                    %th2 = -delta_om/2:stepTh:2*pi-delta_om/2;
                    th2 = th1_f-2*pi:stepTh:thf;
                else
                    th2 = th1_f-2*pi:stepTh:thf;
                end
                %}
                if th1_f > 2*pi
                    th1_f = th1_f - 2*pi;
                end
                th2 = th1_f-2*pi:stepTh:thf;
            elseif app.pointbButton.Value == 1
                th1_i = pi + delta_om/2;
                th1_f = pi-delta_om/2;
                
                if th0 <= th1_i
                    th1 = th0:stepTh:th1_i;
                else
                    th1 = th0:stepTh:2*pi+th1_i;
                end
                if th1_f > 2*pi
                    th1_f = th1_f - 2*pi;
                end
                if thf <= th1_f
                    th2 = th1_f:stepTh:thf+2*pi;
                else
                    th2 = th1_f:stepTh:thf;
                end


            end
            
                
            app.th_mEditField_2.Value = rad2deg(th1_i);
            app.th_mEditField_3.Value = rad2deg(th1_f);
            %if th1_f > 2*pi
            %    app.th_mEditField_2.Value = app.th_mEditField_2.Value - 360;
            %end
            app.e_fEditField_3.Value = ei;
            app.a_fEditField_3.Value = ai;
            
            R3 = [cos(OM) sin(OM) 0; -sin(OM) cos(OM) 0; 0 0 1];
            R2 = [1 0 0; 0 cos(i) sin(i); 0 -sin(i) cos(i)];
            R1 = [cos(om_i) sin(om_i) 0; -sin(om_i) cos(om_i) 0; 0 0 1];
            R = R1*R2*R3;

            [r1,v1,th1] = kep2car(ai,ei,i,OM,om_i,th1,mu);
            D_v1 = 2*sqrt(mu/(ai*(1-ei^2)))*ei*sin(delta_om/2).*[-sin(th1(end)) cos(th1(end)) 0]';
            D_v1_car = R*D_v1;
            [r2,v2,th2] = kep2car(ai,ei,i,OM,om_f,th2,mu);
            %D_v2_car = v2(:,1)-v1(:,1);
            
            app.D_vEditField.Value = norm(D_v1);
            Kep1 = [ai ei om_i]; Kep2 = [ai ei om_f];
            D_t = 0;
            
            if app.start_counter >= 1 && app.Addtransfer_periapsisCheckBox_2.Value == 1
                %add_descr_transfers(app)
                add_Deltas(app,D_v1_car,[0 0 0]',[0 0 0]',D_t)
                add_r(app,[r1 r2],[v1 v2],[th1 th2])
            end
            if isempty(r2)
                r2 = [r2 r1(:,end)];
                th2 = [th2 th1(end)+stepTh];
            end
            transfer_periapsis_plot(app,r1,r2,v1,v2,th1,th2,Kep1,Kep2,D_v1_car)          
            app.D_vtotxEditField.Value = abs(D_v1_car(1));
            app.D_vtotxEditField.Value = abs(D_v1_car(2));
            app.D_vtotyEditField.Value = abs(D_v1_car(3));
            
            
            %catch 
            %   return
            %end
        end

        % Value changed function: PeriapsisangleButton
        function PeriapsisangleButtonValueChanged(app, event)
            value = app.PeriapsisangleButton.Value;
            if value == 1
                app.periapsis.Visible = "on";
                app.bielliptic.Visible = "off";
                app.elliptical_bi_panel.Visible = "off";

                
            else
                app.periapsis.Visible = "off";
            end
        end

        % Button pushed function: Run_elliptical_2
        function Run_elliptical_2ButtonPushed(app, event)
            if app.Addtransfer_change_planeCheckBox.Value == 0
                cla(app.UIAxes,"reset");
            end
            ai = app.a_iEditField_4.Value; af = ai;
            ei = app.e_iEditField_4.Value; ef = ei;
            i_i = deg2rad(app.i_iEditField.Value); i_f = deg2rad(app.i_fEditField.Value);
            OM_i = deg2rad(app.OM_iEditField_2.Value); OM_f = deg2rad(app.OM_fEditField.Value);
            om_i = deg2rad(app.om_iEditField_4.Value);
            th0 = deg2rad(app.th_iEditField_4.Value);
            mu = app.muEditField.Value;
            th_end = deg2rad(app.th_fEditField_5.Value) ;
            [om_f,th_i,th_f,alpha] = change_plane_check(app,i_i,i_f,OM_i,OM_f,om_i);
            if i_f == 0
                th_i = 2*pi-om_i;
                th_f = th_i;
                om_f = om_i;
                alpha = i_i;
                OM_f = OM_i;
            end
        ri = kep2car(ai,ei,i_i,OM_i,om_i,th_i,mu);
        rf = kep2car(af,ef,i_f,OM_f,om_f,th_f,mu);
        if th_i < pi
            th_f = th_i + pi;
        else 
            th_f = th_i-pi;
        end
        ra = ai*(1-ei^2)/(1-ei);
        dist_i = ra-norm(ri); dist_f = ra-norm(rf);
        if app.FastestButton.Value == 1 
            [~,i] = min([(th_i-th_end),(th_f-th_end)]);
            if i == 1
                th1_f = th_i;
            elseif i == 2
                th1_f = th_f;
            elseif th_i-th0~=th_f-th0
            [~,i] = min([th_i-th0,th_f-th0]);
                if i == 1
                    th1_f = th_i;
                elseif i == 2
                    th1_f = th_f;
                end
            elseif th_i-th0==th_f-th0
                [~,i] = min([abs(pi-th_i),abs(pi-th_f)]);
                if i == 1
                    th1_f = th_i;
                elseif i == 2
                    th1_f = th_f;
                end
            end
        elseif app.MostefficientButton.Value == 1
            if th_i-th0==th_f-th0
                th1_f = min(th_i,th_f);
            end
            [~,i] = min([abs(pi-th_i),abs(pi-th_f)]);
                if i == 1
                    th1_f = th_i;
                elseif i == 2
                    th1_f = th_f;
                end
        end
        app.th_mEditField.Value = rad2deg(th1_f);
        stepTh = 2*pi/180;
        if th0 <= th1_f
            th1 = th0:stepTh:th1_f;
        else
            th1 = th0:stepTh:th1_f+2*pi;
        end

        [r1, v1, th1] = kep2car(ai,ei,i_i,OM_i,om_i,th1,mu);
        if th_end >= th1_f
            th2 = th1_f:stepTh:th_end;
        else
            th2 = th1_f:stepTh:th_end+2*pi;
        end
        if app.dontchangeth_fCheckBox.Value == 1
            th2 = th1_f;
        end
        [r2, v2, th2] = kep2car(af,ef,i_f,OM_f,om_f,th2,mu);
        v_tet = sqrt(mu/(ai*(1-ei^2)))*(1+ei*cos(th1_f));
        D_v = 2*v_tet*sin(alpha/2);
        app.D_vEditField_8.Value = D_v;
        D_v_car1 = v2(:,1)-v1(:,end);

        Kep1 = [ai,ei,i_i,OM_i,om_i]; Kep2 = [af ef i_f OM_f om_f];
        D_t = 0;
        if app.start_counter >= 1 && app.Addtransfer_change_planeCheckBox.Value == 1
                %add_descr_transfers(app)
                add_Deltas(app,D_v_car1,[0 0 0]',[0 0 0]',D_t)
                add_r(app,[r1 r2],[v1 v2],[th1 th2])
        end
        plane_change_plot(app,r1,r2,v1,v2,th1,th2,Kep1,Kep2)


        end

        % Value changed function: ChangeplaneButton
        function ChangeplaneButtonValueChanged(app, event)
            value = app.ChangeplaneButton.Value;
            if value == 1
                app.change_plane.Visible = "on";
            else
                app.change_plane.Visible = "off";
            end
        end

        % Button down function: ConversionsPanel
        function ConversionsPanelButtonDown(app, event)
            
        end

        % Value changed function: aKmEditField
        function aKmEditFieldValueChanged(app, event)
            value = app.aKmEditField.Value;
            
        end

        % Value changed function: eEditField
        function eEditFieldValueChanged(app, event)
            value = app.eEditField.Value;
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
        end

        % Value changed function: PasteintoConstantsCheckBox
        function PasteintoConstantsCheckBoxValueChanged(app, event)
            value = app.PasteintoConstantsCheckBox.Value;
            
        end

        % Value changed function: RunButton_5
        function RunButton_5ValueChanged(app, event)
            value = app.RunButton_5.Value;
            % First Orbit
            Vx_A = app.VxAEditField.Value; Vx_B = app.VxBEditField.Value;
            Vy_A = app.VyAEditField.Value; Vy_B = app.VyBEditField.Value;
            Vz_A = app.VzAEditField.Value; Vz_B = app.VzBEditField.Value;
            x_A = app.XAEditField.Value; x_B = app.XBEditField.Value;
            y_A = app.YAEditField.Value; y_B = app.YBEditField.Value;
            z_A = app.ZAEditField.Value; z_B = app.ZBEditField.Value;
            r_A = [x_A y_A z_A]; v_A = [Vx_A Vy_A Vz_A];
            r_B = [x_B y_B z_B]; v_B = [Vx_B Vy_B Vz_B];
            [aA, eA, iA, OM_A, om_A, th0_A] = car2kep(r_A,v_A);
            [aB, eB, iB, OM_B, om_B, thf_B] = car2kep(r_B,v_b);
            pA = aA*(1-eA^2); pB = aB*(1-eB^2);
            rpA = pA/(1+eA); raA = pA/(1-eA);
            rpB = pB/(1+eB); rpB = pB/(1-eB);
            stepTh = 2*pi/180;
            if iA ~= iB
                disp('\nCambio piano necessario')
                [om_f,th_i,th_f,alpha] = change_plane_check(app,iA,iB,OM_A,OM_B,om_A);
                if app.D_tButton.Value == 1
                    [~,minim] = min(th0_A-th_i,th0_A-th_f);
                    if minim == 1
                        th1 = th_i;
                    else
                        th1 = th_f;
                    end
                    [r1, v1, th1] = kep2car(aA,eA,iA,OM_A,om_A,th0_A:stepTh:th1,mu);
                elseif app.D_VButton.Value == 1
                    r_th_i = kep2car(aA,eA,iA,OM_A,om_A,th_i);
                    r_th_f = kep2car(aA,eA,iA,OM_A,om_A,th_f);
                    if max(r_th_f,r_th_i)==r_th_f
                        th1 = th_f;
                    else
                        th1 = th_i;
                    end
                    [r1, v1, th1] = kep2car(aA,eA,iA,OM_A,om_A,th0_A:stepTh:th1,mu);
                    if th1 < pi
                        [r1_1, v1_1, th1_1] = kep2car(aA,eA,iA,OM_A,om_A,th1:stepTh:pi,mu);
                    else
                        [r1_1, v1_1, th1_1] = kep2car(aA,eA,iA,OM_A,om_A,th1:stepTh:3*pi,mu);
                    end
                end
                %Dt_1 = 
                v_theta = sqrt(mu/pA)*eA*sin(th1);
                app.D_vEditField_8.Value = 2*v_theta*sin(alpha/2);
                
                
                % Plane is changed

                if app.D_VButton.Value == 1
                    if max(raA,raB)/min(raA,raB) < 11.94
                        % elliptic
                        
                    else
                        % bielliptic

                    end
                end

            end
        end

        % Value changed function: useconstantsCheckBox_2
        function useconstantsCheckBox_2ValueChanged(app, event)
            value = app.useconstantsCheckBox_2.Value;
            if value == 1
                app.a_iEditField_2.Value = app.aKmEditField_2.Value;
                app.e_iEditField_2.Value = app.eEditField_2.Value;
                app.om_iEditField_2.Value = app.omEditField_2.Value;
                app.th_iEditField_2.Value = app.th_fEditField.Value;
                app.iEditField_4.Value = app.iEditField_2.Value;
                app.OMEditField_4.Value = app.OMEditField_2.Value;
            end
        end

        % Value changed function: useconstantsCheckBox_4
        function useconstantsCheckBox_4ValueChanged(app, event)
            value = app.useconstantsCheckBox_4.Value;
            app.a_iEditField_4.Value = app.aKmEditField_2.Value;
            app.e_iEditField_4.Value = app.eEditField_2.Value;
            app.om_iEditField_4.Value = app.omEditField_2.Value;
            app.th_iEditField_4.Value = app.th_fEditField.Value;
            app.i_iEditField.Value = app.iEditField_2.Value;
            app.OM_iEditField_2.Value = app.OMEditField_2.Value;
            
        end

        % Button pushed function: FinishButton
        function FinishButtonPushed(app, event)
            app.start_counter = 0;
            for i = 1 : 6
                app.("result"+num2str(i)).D_v = 0;
                app.("D_vEditField_"+num2str(i+1)).Value= 0;
                app.("D_tEditField_"+num2str(i)).Value= 0;
                app.("result"+num2str(i)).D_t = 0;
            end 
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
                try
                    cla(app.UIAxes,'reset')
            app.start_counter = app.start_counter + 1;
                prompt = {['Name of Transfer n.',num2str(app.start_counter),':'], 'Description:'};
                dlgtitle = 'Input';
                fieldsize = [1 20; 3 100];
                definput = {'',''};
                answer = inputdlg(prompt,dlgtitle,fieldsize,definput);
                if app.start_counter == 1
                    app.FirsttransferPanel.Title = answer(1);
                elseif app.start_counter == 2
                    app.SecondtransferPanel.Title = answer(1);
                elseif app.start_counter == 3
                    app.ThirdtransferPanel.Title = answer(1);
                elseif app.start_counter == 4
                    app.FourthtransferPanel.Title = answer(1);
                elseif app.start_counter == 5
                    app.FifthtransferPanel.Title = answer(1);
                elseif app.start_counter == 6
                    app.SixthtransferPanel.Title = answer(1);
                end
                if app.start_counter == 1
                    app.TextArea_1.Value = answer(2);
                elseif app.start_counter == 2
                    app.TextArea_2.Value = answer(2);
                elseif app.start_counter == 3
                    app.TextArea_3.Value = answer(2);
                elseif app.start_counter == 4
                    app.TextArea_4.Value = answer(2);
                elseif app.start_counter == 5
                    app.TextArea_5.Value = answer(2);
                elseif app.start_counter == 6
                    app.TextArea_6.Value = answer(2);
                end
                catch
                    return
                end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.ConversionsPanel = uipanel(app.UIFigure);
            app.ConversionsPanel.Title = 'Conversions';
            app.ConversionsPanel.Visible = 'off';
            app.ConversionsPanel.ButtonDownFcn = createCallbackFcn(app, @ConversionsPanelButtonDown, true);
            app.aKmEditFieldLabel = uilabel(app.ConversionsPanel);
            app.aKmEditFieldLabel.Text = 'a [Km]';
            app.aKmEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.aKmEditField.ValueChangedFcn = createCallbackFcn(app, @aKmEditFieldValueChanged, true);
            app.omLabel = uilabel(app.ConversionsPanel);
            app.omLabel.Text = 'om [°]';
            app.omEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.iLabel = uilabel(app.ConversionsPanel);
            app.iLabel.Text = 'i [°]';
            app.iEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.OMLabel = uilabel(app.ConversionsPanel);
            app.OMLabel.Text = 'OM [°]';
            app.OMEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.eEditFieldLabel = uilabel(app.ConversionsPanel);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.eEditField.ValueChangedFcn = createCallbackFcn(app, @eEditFieldValueChanged, true);
            app.thLabel = uilabel(app.ConversionsPanel);
            app.thLabel.Text = 'th [°]';
            app.thEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.YEditFieldLabel = uilabel(app.ConversionsPanel);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.ZEditFieldLabel = uilabel(app.ConversionsPanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.VxEditFieldLabel = uilabel(app.ConversionsPanel);
            app.VxEditFieldLabel.Text = 'V x';
            app.VxEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.VyEditField_2Label = uilabel(app.ConversionsPanel);
            app.VyEditField_2Label.Text = 'V y';
            app.VyEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.VzEditFieldLabel = uilabel(app.ConversionsPanel);
            app.VzEditFieldLabel.Text = 'V z';
            app.VzEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.ButtonGroup = uibuttongroup(app.ConversionsPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.CartoKepButton = uiradiobutton(app.ButtonGroup);
            app.CartoKepButton.Text = 'Car to Kep';
            app.CartoKepButton.Value = true;
            app.KeptoCarButton = uiradiobutton(app.ButtonGroup);
            app.KeptoCarButton.Text = 'Kep to Car';
            app.XEditFieldLabel = uilabel(app.ConversionsPanel);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.ConversionsPanel, 'numeric');
            app.PasteintoConstantsCheckBox = uicheckbox(app.ConversionsPanel);
            app.PasteintoConstantsCheckBox.ValueChangedFcn = createCallbackFcn(app, @PasteintoConstantsCheckBoxValueChanged, true);
            app.PasteintoConstantsCheckBox.Text = ' Paste into Constants';
            app.RunButton = uibutton(app.ConversionsPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Panel = uipanel(app.UIFigure);
            app.CoordinatesButton = uibutton(app.Panel, 'push');
            app.CoordinatesButton.ButtonPushedFcn = createCallbackFcn(app, @CoordinatesButtonPushed, true);
            app.CoordinatesButton.Text = 'Coordinates';
            app.ConstansButton = uibutton(app.Panel, 'push');
            app.ConstansButton.ButtonPushedFcn = createCallbackFcn(app, @ConstansButtonPushed, true);
            app.ConstansButton.Text = 'Constans';
            app.StateButton = uibutton(app.Panel, 'push');
            app.StateButton.ButtonPushedFcn = createCallbackFcn(app, @StateButtonPushed, true);
            app.StateButton.Text = 'State';
            app.AnimationButton = uibutton(app.Panel, 'push');
            app.AnimationButton.ButtonPushedFcn = createCallbackFcn(app, @AnimationButtonPushed, true);
            app.AnimationButton.Text = 'Animation';
            app.norbitsEditFieldLabel = uilabel(app.Panel);
            app.norbitsEditFieldLabel.Text = 'n orbits';
            app.norbitsEditField = uieditfield(app.Panel, 'numeric');
            app.norbitsEditField.Value = 1;
            app.Button_3 = uibutton(app.Panel, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @Button_3ValueChanged, true);
            app.Button_3.Text = '||';
            app.Button_4 = uibutton(app.Panel, 'state');
            app.Button_4.ValueChangedFcn = createCallbackFcn(app, @Button_4ValueChanged, true);
            app.Button_4.Text = '<<';
            app.Button_5 = uibutton(app.Panel, 'state');
            app.Button_5.Text = '>>';
            app.TransferButton = uibutton(app.Panel, 'state');
            app.TransferButton.ValueChangedFcn = createCallbackFcn(app, @TransferButtonValueChanged, true);
            app.TransferButton.Text = 'Transfer';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputTab = uitab(app.TabGroup);
            app.InputTab.Title = 'Input';
            app.muEditFieldLabel = uilabel(app.InputTab);
            app.muEditFieldLabel.Text = 'mu';
            app.muEditField = uieditfield(app.InputTab, 'numeric');
            app.muEditField.ValueChangedFcn = createCallbackFcn(app, @iEditField_2ValueChanged, true);
            app.muEditField.Value = 398600.433;
            app.th_0EditFieldLabel = uilabel(app.InputTab);
            app.th_0EditFieldLabel.Text = 'th_0';
            app.th_0EditField = uieditfield(app.InputTab, 'numeric');
            app.th_0EditField.ValueChangedFcn = createCallbackFcn(app, @iEditField_2ValueChanged, true);
            app.th_fEditFieldLabel = uilabel(app.InputTab);
            app.th_fEditFieldLabel.Text = 'th_f';
            app.th_fEditField = uieditfield(app.InputTab, 'numeric');
            app.th_fEditField.ValueChangedFcn = createCallbackFcn(app, @iEditField_2ValueChanged, true);
            app.aKmEditField_2Label = uilabel(app.InputTab);
            app.aKmEditField_2Label.Text = 'a [Km]';
            app.aKmEditField_2 = uieditfield(app.InputTab, 'numeric');
            app.aKmEditField_2.ValueChangedFcn = createCallbackFcn(app, @iEditField_2ValueChanged, true);
            app.iEditField_2Label = uilabel(app.InputTab);
            app.iEditField_2Label.Text = 'i [°]';
            app.iEditField_2 = uieditfield(app.InputTab, 'numeric');
            app.iEditField_2.ValueChangedFcn = createCallbackFcn(app, @iEditField_2ValueChanged, true);
            app.OMEditField_2Label = uilabel(app.InputTab);
            app.OMEditField_2Label.Text = 'OM [°]';
            app.OMEditField_2 = uieditfield(app.InputTab, 'numeric');
            app.OMEditField_2.ValueChangedFcn = createCallbackFcn(app, @iEditField_2ValueChanged, true);
            app.eEditField_2Label = uilabel(app.InputTab);
            app.eEditField_2Label.Text = 'e';
            app.eEditField_2 = uieditfield(app.InputTab, 'numeric');
            app.eEditField_2.ValueChangedFcn = createCallbackFcn(app, @iEditField_2ValueChanged, true);
            app.thEditField_2Label = uilabel(app.InputTab);
            app.thEditField_2Label.Text = 'th [°]';
            app.thEditField_2 = uieditfield(app.InputTab, 'numeric');
            app.Delta_thEditFieldLabel = uilabel(app.InputTab);
            app.Delta_thEditFieldLabel.Text = 'Delta_th';
            app.Delta_thEditField = uieditfield(app.InputTab, 'numeric');
            app.Delta_thEditField.ValueChangedFcn = createCallbackFcn(app, @iEditField_2ValueChanged, true);
            app.Delta_thEditField.Value = 0.0174532925199433;
            app.omEditField_2Label = uilabel(app.InputTab);
            app.omEditField_2Label.Text = 'om [°]';
            app.omEditField_2 = uieditfield(app.InputTab, 'numeric');
            app.omEditField_2.ValueChangedFcn = createCallbackFcn(app, @iEditField_2ValueChanged, true);
            app.OtherconstantsTab = uitab(app.TabGroup);
            app.OtherconstantsTab.Title = 'Other constants';
            app.GridLayout = uigridlayout(app.OtherconstantsTab);
            app.EEditFieldLabel = uilabel(app.GridLayout);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.GridLayout, 'numeric');
            app.EEditField.Editable = 'off';
            app.pEditFieldLabel = uilabel(app.GridLayout);
            app.pEditFieldLabel.Text = 'p';
            app.pEditField = uieditfield(app.GridLayout, 'numeric');
            app.pEditField.Editable = 'off';
            app.hLabel = uilabel(app.GridLayout);
            app.hLabel.Text = '|h|';
            app.hEditField_2 = uieditfield(app.GridLayout, 'numeric');
            app.hEditField_2.Editable = 'off';
            app.bEditFieldLabel = uilabel(app.GridLayout);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.GridLayout, 'numeric');
            app.bEditField.Editable = 'off';
            app.rpEditFieldLabel = uilabel(app.GridLayout);
            app.rpEditFieldLabel.Text = 'rp';
            app.rpEditField = uieditfield(app.GridLayout, 'numeric');
            app.rpEditField.Editable = 'off';
            app.raEditFieldLabel = uilabel(app.GridLayout);
            app.raEditFieldLabel.Text = 'ra';
            app.raEditField = uieditfield(app.GridLayout, 'numeric');
            app.raEditField.Editable = 'off';
            app.TransfersTab = uitab(app.TabGroup);
            app.TransfersTab.Title = 'Transfers';
            app.D_vtotxEditFieldLabel = uilabel(app.TransfersTab);
            app.D_vtotxEditFieldLabel.Text = 'D_v tot x';
            app.D_vtotxEditField = uieditfield(app.TransfersTab, 'numeric');
            app.D_vtotyEditFieldLabel = uilabel(app.TransfersTab);
            app.D_vtotyEditFieldLabel.Text = 'D_v tot y';
            app.D_vtotyEditField = uieditfield(app.TransfersTab, 'numeric');
            app.D_vtotzEditFieldLabel = uilabel(app.TransfersTab);
            app.D_vtotzEditFieldLabel.Text = 'D_v tot z';
            app.D_vtotzEditField = uieditfield(app.TransfersTab, 'numeric');
            app.periapsis = uipanel(app.UIFigure);
            app.periapsis.Visible = 'off';
            app.RunButton_4 = uibutton(app.periapsis, 'push');
            app.RunButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunButton_4Pushed, true);
            app.RunButton_4.Text = 'Run';
            app.useconstantsCheckBox_3 = uicheckbox(app.periapsis);
            app.useconstantsCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @useconstantsCheckBox_3ValueChanged, true);
            app.useconstantsCheckBox_3.Text = 'use constants';
            app.ButtonGroup_4 = uibuttongroup(app.periapsis);
            app.pointaButton = uiradiobutton(app.ButtonGroup_4);
            app.pointaButton.Text = 'point a';
            app.pointaButton.Value = true;
            app.pointbButton = uiradiobutton(app.ButtonGroup_4);
            app.pointbButton.Text = 'point b';
            app.a_fEditField_3Label = uilabel(app.periapsis);
            app.a_fEditField_3Label.Visible = 'off';
            app.a_fEditField_3Label.Text = 'a_f';
            app.a_fEditField_3 = uieditfield(app.periapsis, 'numeric');
            app.a_fEditField_3.Editable = 'off';
            app.a_fEditField_3.Visible = 'off';
            app.e_fEditField_3Label = uilabel(app.periapsis);
            app.e_fEditField_3Label.Visible = 'off';
            app.e_fEditField_3Label.Text = 'e_f';
            app.e_fEditField_3 = uieditfield(app.periapsis, 'numeric');
            app.e_fEditField_3.Editable = 'off';
            app.e_fEditField_3.Visible = 'off';
            app.D_vEditFieldLabel = uilabel(app.periapsis);
            app.D_vEditFieldLabel.Text = 'D_v';
            app.D_vEditField = uieditfield(app.periapsis, 'numeric');
            app.D_vEditField.Editable = 'off';
            app.TimeEditField_3Label = uilabel(app.periapsis);
            app.TimeEditField_3Label.Text = 'Time';
            app.TimeEditField_3 = uieditfield(app.periapsis, 'numeric');
            app.TimeEditField_3.Editable = 'off';
            app.iEditField_5Label = uilabel(app.periapsis);
            app.iEditField_5Label.Text = 'i';
            app.iEditField_5 = uieditfield(app.periapsis, 'numeric');
            app.OMEditField_5Label = uilabel(app.periapsis);
            app.OMEditField_5Label.Text = 'OM';
            app.OMEditField_5 = uieditfield(app.periapsis, 'numeric');
            app.Addtransfer_periapsisCheckBox_2 = uicheckbox(app.periapsis);
            app.Addtransfer_periapsisCheckBox_2.Text = 'Add transfer';
            app.Addtransfer_periapsisCheckBox_2.Value = true;
            app.FirstorbitPanel = uipanel(app.periapsis);
            app.FirstorbitPanel.Title = 'First orbit';
            app.InputPanel = uipanel(app.periapsis);
            app.InputPanel.Title = 'Input';
            app.a_iEditField_3Label = uilabel(app.InputPanel);
            app.a_iEditField_3Label.Text = 'a_i';
            app.a_iEditField_3 = uieditfield(app.InputPanel, 'numeric');
            app.e_iEditField_3Label = uilabel(app.InputPanel);
            app.e_iEditField_3Label.Text = 'e_i';
            app.e_iEditField_3 = uieditfield(app.InputPanel, 'numeric');
            app.om_iEditField_3Label = uilabel(app.InputPanel);
            app.om_iEditField_3Label.Text = 'om_i';
            app.om_iEditField_3 = uieditfield(app.InputPanel, 'numeric');
            app.th_iEditField_3Label = uilabel(app.InputPanel);
            app.th_iEditField_3Label.Text = 'th_i';
            app.th_iEditField_3 = uieditfield(app.InputPanel, 'numeric');
            app.OutputPanel = uipanel(app.periapsis);
            app.OutputPanel.Title = 'Output';
            app.th_mEditField_2Label = uilabel(app.OutputPanel);
            app.th_mEditField_2Label.Text = 'th_m';
            app.th_mEditField_2 = uieditfield(app.OutputPanel, 'numeric');
            app.th_mEditField_2.Value = 180;
            app.FinalorbitPanel_2 = uipanel(app.periapsis);
            app.FinalorbitPanel_2.Title = 'Final orbit';
            app.InputPanel_2 = uipanel(app.periapsis);
            app.InputPanel_2.Title = 'Input';
            app.om_fEditField_3Label = uilabel(app.InputPanel_2);
            app.om_fEditField_3Label.Text = 'om_f';
            app.om_fEditField_3 = uieditfield(app.InputPanel_2, 'numeric');
            app.th_fEditField_4Label = uilabel(app.InputPanel_2);
            app.th_fEditField_4Label.Text = 'th_f';
            app.th_fEditField_4 = uieditfield(app.InputPanel_2, 'numeric');
            app.th_fEditField_4.Value = 180;
            app.OutputPanel_2 = uipanel(app.periapsis);
            app.OutputPanel_2.Title = 'Output';
            app.th_mEditField_3Label = uilabel(app.OutputPanel_2);
            app.th_mEditField_3Label.Text = 'th_m';
            app.th_mEditField_3 = uieditfield(app.OutputPanel_2, 'numeric');
            app.th_mEditField_3.Value = 180;
            app.bielliptic = uipanel(app.UIFigure);
            app.bielliptic.Visible = 'off';
            app.Run_bielliptic = uibutton(app.bielliptic, 'push');
            app.Run_bielliptic.ButtonPushedFcn = createCallbackFcn(app, @Run_biellipticPushed, true);
            app.Run_bielliptic.Text = 'Run';
            app.useconstantsCheckBox_2 = uicheckbox(app.bielliptic);
            app.useconstantsCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @useconstantsCheckBox_2ValueChanged, true);
            app.useconstantsCheckBox_2.Text = 'use constants';
            app.ButtonGroup_3 = uibuttongroup(app.bielliptic);
            app.ButtonGroup_3.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_3SelectionChanged, true);
            app.perigeButton = uiradiobutton(app.ButtonGroup_3);
            app.perigeButton.Text = 'perige';
            app.perigeButton.Value = true;
            app.apogeButton = uiradiobutton(app.ButtonGroup_3);
            app.apogeButton.Text = 'apoge';
            app.D_v1EditField_2Label = uilabel(app.bielliptic);
            app.D_v1EditField_2Label.Text = 'D_v1';
            app.D_v1EditField_2 = uieditfield(app.bielliptic, 'numeric');
            app.D_v1EditField_2.Editable = 'off';
            app.TimeEditField_2Label = uilabel(app.bielliptic);
            app.TimeEditField_2Label.Text = 'Time';
            app.TimeEditField_2 = uieditfield(app.bielliptic, 'numeric');
            app.TimeEditField_2.Editable = 'off';
            app.iEditField_4Label = uilabel(app.bielliptic);
            app.iEditField_4Label.Text = 'i';
            app.iEditField_4 = uieditfield(app.bielliptic, 'numeric');
            app.OMEditField_4Label = uilabel(app.bielliptic);
            app.OMEditField_4Label.Text = 'OM';
            app.OMEditField_4 = uieditfield(app.bielliptic, 'numeric');
            app.r_bEditFieldLabel = uilabel(app.bielliptic);
            app.r_bEditFieldLabel.Text = 'r_b';
            app.r_bEditField = uieditfield(app.bielliptic, 'numeric');
            app.Addtransfer_biellipticCheckBox_4 = uicheckbox(app.bielliptic);
            app.Addtransfer_biellipticCheckBox_4.Text = 'Add transfer';
            app.Addtransfer_biellipticCheckBox_4.Value = true;
            app.FirstorbitPanel_6 = uipanel(app.bielliptic);
            app.FirstorbitPanel_6.Title = 'First orbit';
            app.InputPanel_7 = uipanel(app.bielliptic);
            app.InputPanel_7.Title = 'Input';
            app.a_iEditField_2Label = uilabel(app.InputPanel_7);
            app.a_iEditField_2Label.Text = 'a_i';
            app.a_iEditField_2 = uieditfield(app.InputPanel_7, 'numeric');
            app.e_iEditField_2Label = uilabel(app.InputPanel_7);
            app.e_iEditField_2Label.Text = 'e_i';
            app.e_iEditField_2 = uieditfield(app.InputPanel_7, 'numeric');
            app.om_iEditField_2Label = uilabel(app.InputPanel_7);
            app.om_iEditField_2Label.Text = 'om_i';
            app.om_iEditField_2 = uieditfield(app.InputPanel_7, 'numeric');
            app.th_iEditField_2Label = uilabel(app.InputPanel_7);
            app.th_iEditField_2Label.Text = 'th_i';
            app.th_iEditField_2 = uieditfield(app.InputPanel_7, 'numeric');
            app.LastorbitPanel = uipanel(app.bielliptic);
            app.LastorbitPanel.Title = 'Last orbit';
            app.InputPanel_8 = uipanel(app.bielliptic);
            app.InputPanel_8.Title = 'Input';
            app.a_fEditField_2Label = uilabel(app.InputPanel_8);
            app.a_fEditField_2Label.Text = 'a_f';
            app.a_fEditField_2 = uieditfield(app.InputPanel_8, 'numeric');
            app.e_fEditField_2Label = uilabel(app.InputPanel_8);
            app.e_fEditField_2Label.Text = 'e_f';
            app.e_fEditField_2 = uieditfield(app.InputPanel_8, 'numeric');
            app.om_fEditField_2Label = uilabel(app.InputPanel_8);
            app.om_fEditField_2Label.Text = 'om_f';
            app.om_fEditField_2 = uieditfield(app.InputPanel_8, 'numeric');
            app.om_fEditField_2.Editable = 'off';
            app.th_fEditField_3Label = uilabel(app.InputPanel_8);
            app.th_fEditField_3Label.Text = 'th_f';
            app.th_fEditField_3 = uieditfield(app.InputPanel_8, 'numeric');
            app.sttransferorbitPanel = uipanel(app.bielliptic);
            app.sttransferorbitPanel.Title = '1st transfer orbit';
            app.OutputPanel_5 = uipanel(app.bielliptic);
            app.OutputPanel_5.Title = 'Output';
            app.a_t1EditFieldLabel = uilabel(app.OutputPanel_5);
            app.a_t1EditFieldLabel.Text = 'a_t1';
            app.a_t1EditField = uieditfield(app.OutputPanel_5, 'numeric');
            app.a_t1EditField.Editable = 'off';
            app.e_t1EditFieldLabel = uilabel(app.OutputPanel_5);
            app.e_t1EditFieldLabel.Text = 'e_t1';
            app.e_t1EditField = uieditfield(app.OutputPanel_5, 'numeric');
            app.e_t1EditField.Editable = 'off';
            app.ndtransferorbitPanel = uipanel(app.bielliptic);
            app.ndtransferorbitPanel.Title = '2nd transfer orbit';
            app.OutputPanel_6 = uipanel(app.bielliptic);
            app.OutputPanel_6.Title = 'Output';
            app.a_t2EditFieldLabel = uilabel(app.OutputPanel_6);
            app.a_t2EditFieldLabel.Text = 'a_t2';
            app.a_t2EditField = uieditfield(app.OutputPanel_6, 'numeric');
            app.a_t2EditField.Editable = 'off';
            app.e_t2EditFieldLabel = uilabel(app.OutputPanel_6);
            app.e_t2EditFieldLabel.Text = 'e_t2';
            app.e_t2EditField = uieditfield(app.OutputPanel_6, 'numeric');
            app.e_t2EditField.Editable = 'off';
            app.D_v2EditField_2Label = uilabel(app.bielliptic);
            app.D_v2EditField_2Label.Text = 'D_v2';
            app.D_v2EditField_2 = uieditfield(app.bielliptic, 'numeric');
            app.D_v2EditField_2.Editable = 'off';
            app.D_v3EditFieldLabel = uilabel(app.bielliptic);
            app.D_v3EditFieldLabel.Text = 'D_v3';
            app.D_v3EditField = uieditfield(app.bielliptic, 'numeric');
            app.D_v3EditField.Editable = 'off';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.Panel2.Visible = 'off';
            app.VxAEditFieldLabel = uilabel(app.Panel2);
            app.VxAEditFieldLabel.Text = 'Vx A';
            app.VxAEditField = uieditfield(app.Panel2, 'numeric');
            app.VyAEditFieldLabel = uilabel(app.Panel2);
            app.VyAEditFieldLabel.Text = 'Vy A';
            app.VyAEditField = uieditfield(app.Panel2, 'numeric');
            app.VzAEditFieldLabel = uilabel(app.Panel2);
            app.VzAEditFieldLabel.Text = 'Vz A';
            app.VzAEditField = uieditfield(app.Panel2, 'numeric');
            app.XAEditFieldLabel = uilabel(app.Panel2);
            app.XAEditFieldLabel.Text = 'X A';
            app.XAEditField = uieditfield(app.Panel2, 'numeric');
            app.YAEditFieldLabel = uilabel(app.Panel2);
            app.YAEditFieldLabel.Text = 'Y A';
            app.YAEditField = uieditfield(app.Panel2, 'numeric');
            app.ZAEditFieldLabel = uilabel(app.Panel2);
            app.ZAEditFieldLabel.Text = 'Z A';
            app.ZAEditField = uieditfield(app.Panel2, 'numeric');
            app.VxBEditFieldLabel = uilabel(app.Panel2);
            app.VxBEditFieldLabel.Text = 'Vx B';
            app.VxBEditField = uieditfield(app.Panel2, 'numeric');
            app.VyBEditFieldLabel = uilabel(app.Panel2);
            app.VyBEditFieldLabel.Text = 'Vy B';
            app.VyBEditField = uieditfield(app.Panel2, 'numeric');
            app.VzBEditFieldLabel = uilabel(app.Panel2);
            app.VzBEditFieldLabel.Text = 'Vz B';
            app.VzBEditField = uieditfield(app.Panel2, 'numeric');
            app.XBEditFieldLabel = uilabel(app.Panel2);
            app.XBEditFieldLabel.Text = 'X B';
            app.XBEditField = uieditfield(app.Panel2, 'numeric');
            app.YBEditFieldLabel = uilabel(app.Panel2);
            app.YBEditFieldLabel.Text = 'Y B';
            app.YBEditField = uieditfield(app.Panel2, 'numeric');
            app.ZBEditFieldLabel = uilabel(app.Panel2);
            app.ZBEditFieldLabel.Text = 'Z B';
            app.ZBEditField = uieditfield(app.Panel2, 'numeric');
            app.RunButton_5 = uibutton(app.Panel2, 'state');
            app.RunButton_5.ValueChangedFcn = createCallbackFcn(app, @RunButton_5ValueChanged, true);
            app.RunButton_5.Text = 'Run';
            app.ButtonGroup_5 = uibuttongroup(app.Panel2);
            app.D_tButton = uiradiobutton(app.ButtonGroup_5);
            app.D_tButton.Text = 'D_t';
            app.D_tButton.Value = true;
            app.D_VButton = uiradiobutton(app.ButtonGroup_5);
            app.D_VButton.Text = 'D_V';
            app.SavedtransfersPanel = uipanel(app.UIFigure);
            app.SavedtransfersPanel.Title = 'Saved transfers';
            app.FirsttransferPanel = uipanel(app.SavedtransfersPanel);
            app.FirsttransferPanel.Title = 'First transfer';
            app.TextArea_1 = uitextarea(app.FirsttransferPanel);
            app.D_vEditField_2Label = uilabel(app.FirsttransferPanel);
            app.D_vEditField_2Label.Text = 'D_v';
            app.D_vEditField_2 = uieditfield(app.FirsttransferPanel, 'numeric');
            app.D_tEditFieldLabel = uilabel(app.FirsttransferPanel);
            app.D_tEditFieldLabel.Text = 'D_t';
            app.D_tEditField_1 = uieditfield(app.FirsttransferPanel, 'numeric');
            app.SecondtransferPanel = uipanel(app.SavedtransfersPanel);
            app.SecondtransferPanel.Title = 'Second transfer';
            app.TextArea_2 = uitextarea(app.SecondtransferPanel);
            app.D_vEditField_3Label = uilabel(app.SecondtransferPanel);
            app.D_vEditField_3Label.Text = 'D_v';
            app.D_vEditField_3 = uieditfield(app.SecondtransferPanel, 'numeric');
            app.D_tEditField_2Label = uilabel(app.SecondtransferPanel);
            app.D_tEditField_2Label.Text = 'D_t';
            app.D_tEditField_2 = uieditfield(app.SecondtransferPanel, 'numeric');
            app.ThirdtransferPanel = uipanel(app.SavedtransfersPanel);
            app.ThirdtransferPanel.Title = 'Third transfer';
            app.TextArea_3 = uitextarea(app.ThirdtransferPanel);
            app.D_vEditField_4Label = uilabel(app.ThirdtransferPanel);
            app.D_vEditField_4Label.Text = 'D_v';
            app.D_vEditField_4 = uieditfield(app.ThirdtransferPanel, 'numeric');
            app.D_tEditField_3Label = uilabel(app.ThirdtransferPanel);
            app.D_tEditField_3Label.Text = 'D_t';
            app.D_tEditField_3 = uieditfield(app.ThirdtransferPanel, 'numeric');
            app.FourthtransferPanel = uipanel(app.SavedtransfersPanel);
            app.FourthtransferPanel.Title = 'Fourth transfer';
            app.TextArea_4 = uitextarea(app.FourthtransferPanel);
            app.D_vEditField_5Label = uilabel(app.FourthtransferPanel);
            app.D_vEditField_5Label.Text = 'D_v';
            app.D_vEditField_5 = uieditfield(app.FourthtransferPanel, 'numeric');
            app.D_tEditField_4Label = uilabel(app.FourthtransferPanel);
            app.D_tEditField_4Label.Text = 'D_t';
            app.D_tEditField_4 = uieditfield(app.FourthtransferPanel, 'numeric');
            app.FifthtransferPanel = uipanel(app.SavedtransfersPanel);
            app.FifthtransferPanel.Title = 'Fifth transfer';
            app.TextArea_5 = uitextarea(app.FifthtransferPanel);
            app.D_vEditField_6Label = uilabel(app.FifthtransferPanel);
            app.D_vEditField_6Label.Text = 'D_v';
            app.D_vEditField_6 = uieditfield(app.FifthtransferPanel, 'numeric');
            app.D_tEditField_5Label = uilabel(app.FifthtransferPanel);
            app.D_tEditField_5Label.Text = 'D_t';
            app.D_tEditField_5 = uieditfield(app.FifthtransferPanel, 'numeric');
            app.SixthtransferPanel = uipanel(app.SavedtransfersPanel);
            app.SixthtransferPanel.Title = 'Sixth transfer';
            app.TextArea_6 = uitextarea(app.SixthtransferPanel);
            app.D_vEditField_7Label = uilabel(app.SixthtransferPanel);
            app.D_vEditField_7Label.Text = 'D_v';
            app.D_vEditField_7 = uieditfield(app.SixthtransferPanel, 'numeric');
            app.D_tEditField_6Label = uilabel(app.SixthtransferPanel);
            app.D_tEditField_6Label.Text = 'D_t';
            app.D_tEditField_6 = uieditfield(app.SixthtransferPanel, 'numeric');
            app.TransferpanelPanel = uipanel(app.UIFigure);
            app.TransferpanelPanel.Title = 'Transfer panel';
            app.TabGroup2 = uitabgroup(app.TransferpanelPanel);
            app.TabGroup2.Visible = 'off';
            app.ImpulseTab = uitab(app.TabGroup2);
            app.ImpulseTab.Title = '1 Impulse';
            app.PeriapsisangleButton = uibutton(app.ImpulseTab, 'state');
            app.PeriapsisangleButton.ValueChangedFcn = createCallbackFcn(app, @PeriapsisangleButtonValueChanged, true);
            app.PeriapsisangleButton.Text = 'Periapsis angle';
            app.ChangeplaneButton = uibutton(app.ImpulseTab, 'state');
            app.ChangeplaneButton.ValueChangedFcn = createCallbackFcn(app, @ChangeplaneButtonValueChanged, true);
            app.ChangeplaneButton.Text = 'Change plane';
            app.ImpulsesTab_2 = uitab(app.TabGroup2);
            app.ImpulsesTab_2.Title = '2 Impulses';
            app.EllipticalbitangentButton = uibutton(app.ImpulsesTab_2, 'state');
            app.EllipticalbitangentButton.ValueChangedFcn = createCallbackFcn(app, @EllipticalbitangentButtonValueChanged, true);
            app.EllipticalbitangentButton.Text = 'Elliptical bitangent';
            app.ImpulsesTab_3 = uitab(app.TabGroup2);
            app.ImpulsesTab_3.Title = '3 Impulses';
            app.BiellipticButton = uibutton(app.ImpulsesTab_3, 'state');
            app.BiellipticButton.ValueChangedFcn = createCallbackFcn(app, @BiellipticButtonValueChanged, true);
            app.BiellipticButton.Text = 'Bielliptic';
            app.FinishButton = uibutton(app.TransferpanelPanel, 'push');
            app.FinishButton.ButtonPushedFcn = createCallbackFcn(app, @FinishButtonPushed, true);
            app.FinishButton.Text = 'Finish';
            app.StartButton = uibutton(app.TransferpanelPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FirstorbitPanel_3 = uipanel(app.UIFigure);
            app.FirstorbitPanel_3.Title = 'First orbit';
            app.FirstorbitPanel_3.Visible = 'off';
            app.elliptical_bi_panel = uipanel(app.UIFigure);
            app.elliptical_bi_panel.Visible = 'off';
            app.ButtonGroup_2 = uibuttongroup(app.elliptical_bi_panel);
            app.ButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_2SelectionChanged, true);
            app.ButtonGroup_2.ButtonDownFcn = createCallbackFcn(app, @ButtonGroup_2ButtonDown, true);
            app.perigeapogeButton = uiradiobutton(app.ButtonGroup_2);
            app.perigeapogeButton.Text = 'perige-apoge';
            app.perigeapogeButton.Value = true;
            app.apogeperigeButton = uiradiobutton(app.ButtonGroup_2);
            app.apogeperigeButton.Text = 'apoge-perige';
            app.apogeapogeButton = uiradiobutton(app.ButtonGroup_2);
            app.apogeapogeButton.Text = 'apoge-apoge';
            app.perigeperigeButton = uiradiobutton(app.ButtonGroup_2);
            app.perigeperigeButton.Text = 'perige-perige';
            app.Run_elliptical = uibutton(app.elliptical_bi_panel, 'push');
            app.Run_elliptical.ButtonPushedFcn = createCallbackFcn(app, @Run_ellipticalPushed, true);
            app.Run_elliptical.Text = 'Run';
            app.useconstantsCheckBox = uicheckbox(app.elliptical_bi_panel);
            app.useconstantsCheckBox.ValueChangedFcn = createCallbackFcn(app, @useconstantsCheckBoxValueChanged, true);
            app.useconstantsCheckBox.Text = 'use constants';
            app.D_v1EditFieldLabel = uilabel(app.elliptical_bi_panel);
            app.D_v1EditFieldLabel.Text = 'D_v1';
            app.D_v1EditField = uieditfield(app.elliptical_bi_panel, 'numeric');
            app.D_v1EditField.Editable = 'off';
            app.D_v2EditFieldLabel = uilabel(app.elliptical_bi_panel);
            app.D_v2EditFieldLabel.Text = 'D_v2';
            app.D_v2EditField = uieditfield(app.elliptical_bi_panel, 'numeric');
            app.D_v2EditField.Editable = 'off';
            app.TimeEditFieldLabel = uilabel(app.elliptical_bi_panel);
            app.TimeEditFieldLabel.Text = 'Time';
            app.TimeEditField = uieditfield(app.elliptical_bi_panel, 'numeric');
            app.TimeEditField.Editable = 'off';
            app.iEditField_3Label = uilabel(app.elliptical_bi_panel);
            app.iEditField_3Label.Text = 'i';
            app.iEditField_3 = uieditfield(app.elliptical_bi_panel, 'numeric');
            app.OMEditField_3Label = uilabel(app.elliptical_bi_panel);
            app.OMEditField_3Label.Text = 'OM';
            app.OMEditField_3 = uieditfield(app.elliptical_bi_panel, 'numeric');
            app.Addtransfer_ellipticalCheckBox_3 = uicheckbox(app.elliptical_bi_panel);
            app.Addtransfer_ellipticalCheckBox_3.Text = 'Add transfer';
            app.Addtransfer_ellipticalCheckBox_3.Value = true;
            app.FirstorbitPanel_4 = uipanel(app.elliptical_bi_panel);
            app.FirstorbitPanel_4.Title = 'First orbit';
            app.InputPanel_3 = uipanel(app.elliptical_bi_panel);
            app.InputPanel_3.Title = 'Input';
            app.a_iEditFieldLabel = uilabel(app.InputPanel_3);
            app.a_iEditFieldLabel.Text = 'a_i';
            app.a_iEditField = uieditfield(app.InputPanel_3, 'numeric');
            app.a_iEditField.ValueChangedFcn = createCallbackFcn(app, @e_iEditFieldValueChanged, true);
            app.e_iEditFieldLabel = uilabel(app.InputPanel_3);
            app.e_iEditFieldLabel.Text = 'e_i';
            app.e_iEditField = uieditfield(app.InputPanel_3, 'numeric');
            app.e_iEditField.ValueChangedFcn = createCallbackFcn(app, @e_iEditFieldValueChanged, true);
            app.om_iEditFieldLabel = uilabel(app.InputPanel_3);
            app.om_iEditFieldLabel.Text = 'om_i';
            app.om_iEditField = uieditfield(app.InputPanel_3, 'numeric');
            app.om_iEditField.ValueChangedFcn = createCallbackFcn(app, @e_iEditFieldValueChanged, true);
            app.th_iEditFieldLabel = uilabel(app.InputPanel_3);
            app.th_iEditFieldLabel.Text = 'th_i';
            app.th_iEditField = uieditfield(app.InputPanel_3, 'numeric');
            app.TransferorbitPanel = uipanel(app.elliptical_bi_panel);
            app.TransferorbitPanel.Title = 'Transfer orbit';
            app.a_tEditFieldLabel = uilabel(app.elliptical_bi_panel);
            app.a_tEditFieldLabel.Text = 'a_t';
            app.a_tEditField = uieditfield(app.elliptical_bi_panel, 'numeric');
            app.a_tEditField.Editable = 'off';
            app.e_tEditFieldLabel = uilabel(app.elliptical_bi_panel);
            app.e_tEditFieldLabel.Text = 'e_t';
            app.e_tEditField = uieditfield(app.elliptical_bi_panel, 'numeric');
            app.e_tEditField.Editable = 'off';
            app.FinalorbitPanel_3 = uipanel(app.elliptical_bi_panel);
            app.FinalorbitPanel_3.Title = 'Final orbit';
            app.InputPanel_4 = uipanel(app.elliptical_bi_panel);
            app.InputPanel_4.Title = 'Input';
            app.a_fEditFieldLabel = uilabel(app.InputPanel_4);
            app.a_fEditFieldLabel.Text = 'a_f';
            app.a_fEditField = uieditfield(app.InputPanel_4, 'numeric');
            app.e_fEditFieldLabel = uilabel(app.InputPanel_4);
            app.e_fEditFieldLabel.Text = 'e_f';
            app.e_fEditField = uieditfield(app.InputPanel_4, 'numeric');
            app.th_fEditField_2Label = uilabel(app.InputPanel_4);
            app.th_fEditField_2Label.Text = 'th_f';
            app.th_fEditField_2 = uieditfield(app.InputPanel_4, 'numeric');
            app.th_fEditField_2.Value = 180;
            app.OutputPanel_3 = uipanel(app.elliptical_bi_panel);
            app.OutputPanel_3.Title = 'Output';
            app.om_fEditFieldLabel = uilabel(app.OutputPanel_3);
            app.om_fEditFieldLabel.Text = 'om_f';
            app.om_fEditField = uieditfield(app.OutputPanel_3, 'numeric');
            app.om_fEditField.Editable = 'off';
            app.change_plane = uipanel(app.UIFigure);
            app.change_plane.Visible = 'off';
            app.Run_elliptical_2 = uibutton(app.change_plane, 'push');
            app.Run_elliptical_2.ButtonPushedFcn = createCallbackFcn(app, @Run_elliptical_2ButtonPushed, true);
            app.Run_elliptical_2.Text = 'Run';
            app.useconstantsCheckBox_4 = uicheckbox(app.change_plane);
            app.useconstantsCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @useconstantsCheckBox_4ValueChanged, true);
            app.useconstantsCheckBox_4.Text = 'use constants';
            app.TimeEditField_4Label = uilabel(app.change_plane);
            app.TimeEditField_4Label.Text = 'Time';
            app.TimeEditField_4 = uieditfield(app.change_plane, 'numeric');
            app.TimeEditField_4.Editable = 'off';
            app.th_mEditFieldLabel = uilabel(app.change_plane);
            app.th_mEditFieldLabel.Text = 'th_m';
            app.th_mEditField = uieditfield(app.change_plane, 'numeric');
            app.Addtransfer_change_planeCheckBox = uicheckbox(app.change_plane);
            app.Addtransfer_change_planeCheckBox.Text = 'Add transfer';
            app.Addtransfer_change_planeCheckBox.Value = true;
            app.ButtonGroup_6 = uibuttongroup(app.change_plane);
            app.FastestButton = uiradiobutton(app.ButtonGroup_6);
            app.FastestButton.Text = 'Fastest';
            app.FastestButton.Value = true;
            app.MostefficientButton = uiradiobutton(app.ButtonGroup_6);
            app.MostefficientButton.Text = 'Most efficient';
            app.dontchangeth_fCheckBox = uicheckbox(app.change_plane);
            app.dontchangeth_fCheckBox.Text = 'don''t change th_f';
            app.FirstorbitPanel_5 = uipanel(app.change_plane);
            app.FirstorbitPanel_5.Title = 'First orbit';
            app.InputPanel_5 = uipanel(app.change_plane);
            app.InputPanel_5.Title = 'Input';
            app.a_iEditField_4Label = uilabel(app.InputPanel_5);
            app.a_iEditField_4Label.Text = 'a_i';
            app.a_iEditField_4 = uieditfield(app.InputPanel_5, 'numeric');
            app.e_iEditField_4Label = uilabel(app.InputPanel_5);
            app.e_iEditField_4Label.Text = 'e_i';
            app.e_iEditField_4 = uieditfield(app.InputPanel_5, 'numeric');
            app.om_iEditField_4Label = uilabel(app.InputPanel_5);
            app.om_iEditField_4Label.Text = 'om_i';
            app.om_iEditField_4 = uieditfield(app.InputPanel_5, 'numeric');
            app.th_iEditField_4Label = uilabel(app.InputPanel_5);
            app.th_iEditField_4Label.Text = 'th_i';
            app.th_iEditField_4 = uieditfield(app.InputPanel_5, 'numeric');
            app.i_iEditFieldLabel = uilabel(app.InputPanel_5);
            app.i_iEditFieldLabel.Text = 'i_i';
            app.i_iEditField = uieditfield(app.InputPanel_5, 'numeric');
            app.OM_iEditField_2Label = uilabel(app.InputPanel_5);
            app.OM_iEditField_2Label.Text = 'OM_i';
            app.OM_iEditField_2 = uieditfield(app.InputPanel_5, 'numeric');
            app.FinalorbitPanel_4 = uipanel(app.change_plane);
            app.FinalorbitPanel_4.Title = 'Final orbit';
            app.InputPanel_6 = uipanel(app.change_plane);
            app.InputPanel_6.Title = 'Input';
            app.th_fEditField_5Label = uilabel(app.InputPanel_6);
            app.th_fEditField_5Label.Text = 'th_f';
            app.th_fEditField_5 = uieditfield(app.InputPanel_6, 'numeric');
            app.th_fEditField_5.Value = 180;
            app.OM_fEditFieldLabel = uilabel(app.InputPanel_6);
            app.OM_fEditFieldLabel.Text = 'OM_f';
            app.OM_fEditField = uieditfield(app.InputPanel_6, 'numeric');
            app.i_fEditFieldLabel = uilabel(app.InputPanel_6);
            app.i_fEditFieldLabel.Text = 'i_f';
            app.i_fEditField = uieditfield(app.InputPanel_6, 'numeric');
            app.OutputPanel_4 = uipanel(app.change_plane);
            app.OutputPanel_4.Title = 'Output';
            app.om_fEditField_4Label = uilabel(app.OutputPanel_4);
            app.om_fEditField_4Label.Text = 'om_f';
            app.om_fEditField_4 = uieditfield(app.OutputPanel_4, 'numeric');
            app.om_fEditField_4.Editable = 'off';
            app.a_fEditField_4Label = uilabel(app.OutputPanel_4);
            app.a_fEditField_4Label.Text = 'a_f';
            app.a_fEditField_4 = uieditfield(app.OutputPanel_4, 'numeric');
            app.a_fEditField_4.Editable = 'off';
            app.e_fEditField_4Label = uilabel(app.OutputPanel_4);
            app.e_fEditField_4Label.Text = 'e_f';
            app.e_fEditField_4 = uieditfield(app.OutputPanel_4, 'numeric');
            app.e_fEditField_4.Editable = 'off';
            app.D_vEditField_8Label = uilabel(app.change_plane);
            app.D_vEditField_8Label.Text = 'D_v';
            app.D_vEditField_8 = uieditfield(app.change_plane, 'numeric');
            app.D_vEditField_8.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotOrbits_application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end